classdef AppBancadaPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ResetarAplicativoMenu           matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        PreferenciasMenu                matlab.ui.container.Menu
        MotoresMenu                     matlab.ui.container.Menu
        Gravar                          matlab.ui.control.Button
        UnidadePanel                    matlab.ui.container.Panel
        KgCheckBox                      matlab.ui.control.CheckBox
        NCheckBox                       matlab.ui.control.CheckBox
        StatusPanel                     matlab.ui.container.Panel
        PanelCalibracao                 matlab.ui.container.Panel
        BtnFecharCalibracao             matlab.ui.control.Button
        BtnCalcularFator                matlab.ui.control.Button
        InputPesoRef                    matlab.ui.control.NumericEditField
        PesoKgLabel                     matlab.ui.control.Label
        Passo2ColoqueumpesoconhecidokgLabel  matlab.ui.control.Label
        BtnLerZero                      matlab.ui.control.Button
        Passo1RemovaopesodabalanaLabel  matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        Tarar                           matlab.ui.control.Button
        Limpar                          matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        Calibrar                        matlab.ui.control.Button
        AquisiaodedadosparabancadadetesteestaticoLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Lamp                            matlab.ui.control.Lamp
        PararAquisicao                  matlab.ui.control.Button
        IniciarAquisicao                matlab.ui.control.Button
        Grafico1                        matlab.ui.control.UIAxes
        Grafico2                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

properties (Access = private)
        % Objeto da sessão da National Instruments
        DaqSession

        % Trava para impedir cliques duplos no botão iniciar
        IsRunning logical = false

        % Buffers (Memória) para o gráfico não piscar
        HistoryTime = [];
        HistoryForca = [];
        HistoryPressao = [];
        
        % --- MUDANÇAS AQUI ---
        TimeOffset = 0;   % Mudei de seconds(0) para 0 (número simples)
        T0 = [];          % <--- ADICIONE ISSO (Serve para zerar o tempo gigante)
        % ---------------------

        % Variáveis de Calibração (Zero)
        OffsetForca = 0;
        OffsetPressao = 0;

        % Fatores de Conversão (Atualizados 28/11)
        FatorForca = 519.35;    % Calibrado com 5.4kg
        FatorPressao = 400.0;   % Sensor 1600 PSI
        
        % ============================================================
        % NOVAS VARIÁVEIS PARA O BOTÃO GRAVAR (CSV)
        % ============================================================
        IsRecording logical = false; % Chave: Está gravando ou não?
        
        % Buffers exclusivos para o CSV (Dados Brutos)
        RecTime = [];
        RecForce = [];
        RecPressure = [];
    end

methods (Access = private)
        
        % --- CONFIGURAÇÃO (AGORA COM RESET DE RELÓGIO) ---
        function ConfigurarDAQ(app)
            % Se a sessão existe mas está inválida, deleta ela
            if ~isempty(app.DaqSession) && ~isvalid(app.DaqSession)
                app.DaqSession = [];
            end
            
            % Se já está tudo pronto, sai
            if ~isempty(app.DaqSession)
                return;
            end

            % --- RESET FORÇADO DO HARDWARE ---
            % Isso zera o relógio interno da placa NI (evita o 400000s)
            daqreset; 
            % ---------------------------------

            app.DaqSession = daq("ni");
            app.DaqSession.Rate = 1000; 

            % Configura Canais
            % Canal 0: Força (Cria variável ch1 para configurar com segurança)
            ch1 = addinput(app.DaqSession, "Dev1", "ai0", "Voltage");
            ch1.TerminalConfig = "SingleEnded";
            
            % Canal 1: Pressão (Cria variável ch2 para configurar com segurança)
            ch2 = addinput(app.DaqSession, "Dev1", "ai1", "Voltage");
            ch2.TerminalConfig = "SingleEnded";
            
            app.DaqSession.ScansAvailableFcn = @(src, event) processarDados(app, src, event);
            app.DaqSession.ScansAvailableFcnCount = 100;
        end

        % --- PROCESSAMENTO (MATEMÁTICA DO TEMPO ZERO) ---
        function processarDados(app, src, ~)
            % 1. Ler dados brutos
            [data, timestamps] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            
            % ============================================================
            % LÓGICA DO TEMPO ZERO (CORREÇÃO DO EIXO X)
            % ============================================================
            
            % Se T0 estiver vazio (primeira leitura), ele pega o tempo atual do hardware
            if isempty(app.T0)
                app.T0 = timestamps(1); 
            end
            
            % Subtrai o tempo inicial para começar do 0.0s absoluto
            tempoZero = timestamps - app.T0;
            
            % Soma o tempo de pausa (se houver)
            timeFinal = tempoZero + app.TimeOffset;
            % ============================================================
            
            % 2. Engenharia (Volts -> Newton/PSI)
            valForce = (data(:, 1) - app.OffsetForca) * app.FatorForca;
            valPressure = (data(:, 2) - app.OffsetPressao) * app.FatorPressao;
            
            % 3. Guardar no Histórico
            app.HistoryTime = [app.HistoryTime; timeFinal];
            app.HistoryForca = [app.HistoryForca; valForce];
            app.HistoryPressao = [app.HistoryPressao; valPressure];
            
            % 4. Guardar no CSV (Gravação)
            if app.IsRecording
                app.RecTime = [app.RecTime; timeFinal]; 
                app.RecForce = [app.RecForce; valForce];
                app.RecPressure = [app.RecPressure; valPressure];
            end
            
            % 5. Suavização
            k = 50; 
            if length(app.HistoryForca) > k
                plotForce = movmean(app.HistoryForca, k);
                plotPressure = movmean(app.HistoryPressao, k);
            else
                plotForce = app.HistoryForca;
                plotPressure = app.HistoryPressao;
            end
            
            % 6. Plotar
            plot(app.Grafico2, app.HistoryTime, plotForce, 'w', 'LineWidth', 1);
            plot(app.Grafico1, app.HistoryTime, plotPressure, 'g', 'LineWidth', 1);
            
            % 7. Configuração Visual (Limpa)
            app.Grafico2.XAxis.TickValuesMode = 'auto';
            app.Grafico1.XAxis.TickValuesMode = 'auto';
            
            axis(app.Grafico2, 'auto');
            axis(app.Grafico1, 'auto');
            
            app.Grafico2.XAxis.Exponent = 0; 
            app.Grafico1.XAxis.Exponent = 0;
            xtickformat(app.Grafico2, '%.1f');
            xtickformat(app.Grafico1, '%.1f');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            % 1. Configura o Hardware imediatamente
            try
                % 1. Configura o Hardware
                ConfigurarDAQ(app);
                
                % ============================================================
                % 2. AUTO-TARA (A CORREÇÃO DO PROBLEMA)
                % ============================================================
                % Lê 10 amostras rápidas para descobrir a voltagem de repouso
                dataInicial = read(app.DaqSession, 10, "OutputFormat", "Matrix");
                
                % Define isso como o "Zero" inicial
                app.OffsetForca = mean(dataInicial(:, 1));
                app.OffsetPressao = mean(dataInicial(:, 2));
                
                % Zera o tempo para o gráfico começar em 0.0s
                app.T0 = []; 
                app.TimeOffset = 0;
                % ============================================================

                % 3. Inicia o Monitoramento
                app.IsRunning = true;
                start(app.DaqSession, "Continuous");
                
                % 4. Configura visual dos botões
                app.IniciarAquisicao.Enable = 'off'; 
                app.PararAquisicao.Enable = 'on';    
                
            catch ME
                uialert(app.UIFigure, "Erro no Startup: " + ME.message, "Aviso");
            end
     
       
        end

        % Button pushed function: IniciarAquisicao
        function IniciarAquisicaoButtonPushed(app, event)

            if app.IsRunning
                return; 
            end
            
            try
                % 1. Limpa os vetores (Gráfico novo)
                app.HistoryTime = [];
                app.HistoryForca = [];
                app.HistoryPressao = [];
                
                % ============================================================
                % A CORREÇÃO DO "ESPAÇO VAZIO" ESTÁ AQUI:
                % ============================================================
                app.TimeOffset = 0; % Zera o acumulador de tempo
                app.T0 = [];        % Força o sistema a pegar um novo "Zero Absoluto"
                % ============================================================
                
                % 2. Garante Hardware
                ConfigurarDAQ(app);
                
                % 3. Atualiza status
                app.IsRunning = true;
                app.IniciarAquisicao.Enable = 'off';
                app.PararAquisicao.Enable = 'on';
                
                % 4. Inicia
                start(app.DaqSession, "Continuous");
                
            catch ME
                app.IsRunning = false;
                app.IniciarAquisicao.Enable = 'on';
                app.PararAquisicao.Enable = 'off';
                uialert(app.UIFigure, ME.message, "Erro ao Iniciar");
            end
        
        end

        % Button pushed function: PararAquisicao
        function PararAquisicaoButtonPushed(app, event)
% 1. Para o Hardware imediatamente
            if app.IsRunning
                stop(app.DaqSession);
                app.IsRunning = false;
            end
            
            % 2. Verifica se o modo GRAVAÇÃO estava ligado
            estavaGravando = app.IsRecording;
            
            % 3. Desliga tudo e reseta os botões
            app.IsRecording = false; 
            
            % --- CORREÇÃO VISUAL AQUI ---
            app.Gravar.Text = "GRAVAR";
            app.Gravar.BackgroundColor = [0.25 0.25 0.25]; % <--- AGORA É CINZA ESCURO
            app.Gravar.FontColor = [1 1 1];       % Garante que o texto continue branco
            app.Gravar.Enable = 'on'; 
            % ----------------------------
            
            app.IniciarAquisicao.Enable = 'on';
            app.PararAquisicao.Enable = 'off';
            
            % ============================================================
            % 4. LÓGICA DE SALVAMENTO (SÓ SE ESTAVA GRAVANDO)
            % ============================================================
            if estavaGravando
                try
                    % Segurança: Verifica se gravou algo mesmo
                    if isempty(app.RecTime)
                        uialert(app.UIFigure, "Você ativou a gravação, mas nenhum dado chegou.", "Arquivo Vazio");
                        return;
                    end
                    
                    % Prepara a Tabela para o Excel/CSV
                    T = table(app.RecTime, app.RecForce, app.RecPressure, ...
                        'VariableNames', {'Tempo_s', 'Forca_N', 'Pressao_PSI'});
                    
                    % Gera nome automático: Teste_Ano-Mes-Dia_Hora-Min.csv
                    nomeArquivo = "Teste_" + string(datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss')) + ".csv";
                    
                    % ABRE A JANELA DO WINDOWS PARA ESCOLHER A PASTA
                    [file, path] = uiputfile('*.csv', 'Salvar Dados do Foguete', nomeArquivo);
                    
                    % Se o usuário não cancelou...
                    if ~isequal(file, 0)
                        % SALVA O ARQUIVO NO DISCO
                        writetable(T, fullfile(path, file));
                        
                        % MOSTRA A CONFIRMAÇÃO VERDE
                        uialert(app.UIFigure, "Dados salvos com sucesso!", "Confirmado", 'Icon', 'success');
                    else
                        uialert(app.UIFigure, "Salvamento cancelado pelo usuário. Dados perdidos.", "Aviso");
                    end
                    
                catch ME
                    uialert(app.UIFigure, "Erro ao criar arquivo: " + ME.message, "Erro Crítico");
                end
            end
       
        end

        % Button pushed function: Calibrar
        function CalibrarButtonPushed(app, event)

    % 1. Segurança e Parada do DAQ
    if app.IsRunning
        stop(app.DaqSession);
        app.IsRunning = false;
        app.IniciarAquisicao.Enable = 'on';
        app.PararAquisicao.Enable = 'off';
    end

    % 2. Garante Hardware
    if isempty(app.DaqSession)
        ConfigurarDAQ(app);
    end

    % ============================================================
    % INTERFACE DARK MODE (VISUAL)
    % ============================================================
    
    % Cores do Tema
    CorFundo = [0.15 0.15 0.15]; % Cinza Escuro (Quase preto)
    CorTexto = [1.00 1.00 1.00]; % Branco
    CorInput = [0.25 0.25 0.25]; % Cinza Médio para caixas
    CorVerde = [0.00 0.80 0.00]; % Verde Neon para sucesso
    CorCinza = [0.50 0.50 0.50]; % Texto desabilitado
    
    % Janela Principal Escura
    calibFig = uifigure('Name', 'Assistente de Calibração', ...
        'Position', [500, 400, 450, 350], ...
        'Resize', 'off', ...
        'Color', CorFundo, ... % <--- AQUI MUDA O FUNDO
        'WindowStyle', 'modal'); 
        
    % Título
    uilabel(calibFig, 'Position', [20 310 400 30], ...
        'Text', 'Calibração de Célula de Carga', ...
        'FontSize', 16, 'FontWeight', 'bold', ...
        'FontColor', CorTexto); % Texto Branco

    % --- PASSO 1: INPUT ---
    uilabel(calibFig, 'Position', [20 270 250 22], ...
        'Text', '1) Informe a massa padrão (kg):', ...
        'FontColor', CorTexto);
    
    edtMassa = uieditfield(calibFig, 'numeric', ...
        'Position', [260 270 100 22], ...
        'Value', 5.400, ...
        'BackgroundColor', CorInput, ... % Fundo da caixa escuro
        'FontColor', CorTexto, ...       % Texto da caixa branco
        'Limits', [0.001 1000]);

    % --- PASSO 2: INSTRUÇÃO DE TARA ---
    lblTara = uilabel(calibFig, 'Position', [20 230 400 22], ...
        'Text', '2) Remova todo o peso da célula (Posição Vertical).', ...
        'FontColor', CorTexto); % Começa Branco (Ativo)

    % --- BARRA DE PROGRESSO ---
    barProgresso = uigauge(calibFig, 'linear', ...
        'Position', [20 190 410 30], ...
        'Limits', [0 100], ...
        'Value', 0, ...
        'BackgroundColor', CorInput); % Fundo da barra escuro
        
    % Status
    lblStatus = uilabel(calibFig, 'Position', [20 165 400 22], ...
        'Text', 'Aguardando início...', ...
        'FontColor', [0.8 0.8 0.8]); % Cinza claro

    % --- PASSO 3: INSTRUÇÃO DE CARGA ---
    lblCarga = uilabel(calibFig, 'Position', [20 130 400 22], ...
        'Text', '3) Insira a massa de calibração.', ...
        'Enable', 'off', ...
        'FontColor', CorCinza); % Começa cinza (inativo)

    % --- BOTÕES ---
    uibutton(calibFig, 'Position', [230 20 100 30], ...
        'Text', 'Cancelar', ...
        'BackgroundColor', [0.3 0.3 0.3], ... % Botão cinza
        'FontColor', 'w', ...
        'ButtonPushedFcn', @(o,e) close(calibFig));
        
    btnProximo = uibutton(calibFig, 'Position', [340 20 90 30], ...
        'Text', 'Próximo', ...
        'BackgroundColor', [0.0 0.45 0.74], ... % Azul
        'FontColor', 'w', ...
        'FontWeight', 'bold');

    % ============================================================
    % LÓGICA (ADAPTADA PARA DARK MODE)
    % ============================================================
    
    passoAtual = 1; 
    offsetSalvo = 0;
    
    btnProximo.ButtonPushedFcn = @(src, event) avancarPasso();
    
    function avancarPasso()
        
        % >>> ESTADO 1: TARA
        if passoAtual == 1
            btnProximo.Enable = 'off';
            edtMassa.Enable = 'off';
            lblStatus.Text = 'Lendo Zero (Tara)...';
            lblStatus.FontColor = CorTexto; % Branco
            
            dados_tara = [];
            for i = 1:10
                barProgresso.Value = i * 10;
                chunk = read(app.DaqSession, 100, "OutputFormat", "Matrix");
                dados_tara = [dados_tara; chunk(:,1)]; 
                drawnow; 
            end
            
            offsetSalvo = mean(dados_tara);
            app.OffsetForca = offsetSalvo;
            
            % Zera pressão (silencioso)
            chunkP = read(app.DaqSession, 10, "OutputFormat", "Matrix");
            app.OffsetPressao = mean(chunkP(:, 2)); 
            
            % Atualiza Interface
            barProgresso.Value = 0;
            lblTara.Enable = 'off';
            lblTara.Text = ['2) Tara OK (Zero: ' num2str(offsetSalvo, '%.4f') ' V)'];
            lblTara.FontColor = CorVerde; % Fica Verde quando conclui
            
            lblCarga.Enable = 'on'; 
            lblCarga.FontColor = CorTexto; % Fica Branco quando ativa
            lblCarga.FontWeight = 'bold';
            
            lblStatus.Text = 'Coloque o peso na célula e clique em Calibrar.';
            btnProximo.Text = 'Calibrar';
            btnProximo.Enable = 'on';
            passoAtual = 2;
            return;
        end
        
        % >>> ESTADO 2: LEITURA COM PESO
        if passoAtual == 2
            btnProximo.Enable = 'off';
            lblStatus.Text = 'Lendo Carga...';
            
            dados_peso = [];
            for i = 1:10
                barProgresso.Value = i * 10;
                chunk = read(app.DaqSession, 100, "OutputFormat", "Matrix");
                dados_peso = [dados_peso; chunk(:,1)];
                drawnow;
            end
            
            leituraComPeso = mean(dados_peso);
            massa = edtMassa.Value;
            ForcaN = massa * 9.80665;
            DeltaV = leituraComPeso - offsetSalvo;
            
            if abs(DeltaV) < 0.002
                 uialert(calibFig, 'Erro: Sem variação de voltagem.', 'Erro');
                 btnProximo.Enable = 'on';
                 barProgresso.Value = 0;
                 return;
            end
            
            novoFator = ForcaN / DeltaV;
            app.FatorForca = novoFator;
            
            barProgresso.Value = 100;
            lblStatus.Text = 'Sucesso! Fator Atualizado.';
            lblStatus.FontColor = CorVerde;
            
            msgFinal = sprintf('Calibração Perfeita!\n\nMassa: %.3f kg\nNovo Fator: %.2f N/V', ...
                massa, novoFator);
            
            uialert(calibFig, msgFinal, 'Concluído', 'Icon', 'success', ...
                'CloseFcn', @(o,e) close(calibFig));
        end
    end
        
     
        end

        % Button pushed function: Limpar
        function LimparButtonPushed(app, event)
            % 1. Zera a memória (esquece os dados antigos)
            app.HistoryTime = [];
            app.HistoryForca = [];
            app.HistoryPressao = [];
            
            % 2. Limpa o desenho dos gráficos visualmente
            cla(app.Grafico1); % Limpa Pressão
            cla(app.Grafico2); % Limpa Força
        end

        % Button pushed function: Tarar
        function TararButtonPushed(app, event)
            % 1. Verifica Hardware
            if isempty(app.DaqSession)
                ConfigurarDAQ(app);
            end

            % 2. Verifica se o teste está rodando agora
            estavaRodando = app.IsRunning;
            
            try
                % Se estiver rodando, PAUSA e SALVA O TEMPO
                if estavaRodando
                    stop(app.DaqSession);
                    
                    % --- AQUI ESTAVA FALTANDO! (CORREÇÃO DO BUG) ---
                    % Se já temos dados, salvamos o último segundo (ex: 10s)
                    % para somar depois e o gráfico não voltar pro zero.
                    if ~isempty(app.HistoryTime)
                        app.TimeOffset = app.HistoryTime(end);
                    end
                    % -----------------------------------------------
                end
                
                % 3. Lê o ZERO (Média de 500 amostras)
                data = read(app.DaqSession, 500, "OutputFormat", "Matrix");
                
                % Define os novos Offsets
                app.OffsetForca = mean(data(:, 1));
                app.OffsetPressao = mean(data(:, 2));
                
                % 4. Retoma o teste automaticamente
                if estavaRodando
                    start(app.DaqSession, "Continuous");
                else
                    uialert(app.UIFigure, "Sensores Zerados (Tara Definida).", "Sucesso");
                end
                
            catch ME
                uialert(app.UIFigure, "Erro ao tentar Tarar: " + ME.message, "Erro");
            end
        end

        % Button pushed function: Gravar
        function GravarButtonPushed(app, event)
           
            % 1. Segurança: Só grava se o sistema já estiver rodando
            if ~app.IsRunning
                uialert(app.UIFigure, "Clique em INICIAR primeiro.", "Erro");
                return;
            end
            
            % 2. Limpa os buffers de gravação (começa do zero)
            app.RecTime = [];
            app.RecForce = [];
            app.RecPressure = [];
            
            % 3. ATIVA A CHAVE DE GRAVAÇÃO (O MAIS IMPORTANTE)
            app.IsRecording = true;
            
            % 4. Muda o visual para VERMELHO (Feedback visual)
            app.Gravar.Text = "GRAVANDO...";
            app.Gravar.BackgroundColor = [1 0 0]; % Vermelho [R G B]
            app.Gravar.FontColor = [1 1 1];       % Texto Branco
            app.Gravar.Enable = 'off';            % Trava o botão
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ResetarAplicativoMenu = uimenu(app.Menu);
            app.ResetarAplicativoMenu.Text = 'Resetar Aplicativo';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.PreferenciasMenu = uimenu(app.UIFigure);
            app.PreferenciasMenu.Text = 'Preferencias ';
            app.MotoresMenu = uimenu(app.UIFigure);
            app.MotoresMenu.Text = 'Motores';
            app.Grafico2 = uiaxes(app.UIFigure);
            title(app.Grafico2, 'Força')
            xlabel(app.Grafico2, 'Tempo (s)')
            ylabel(app.Grafico2, 'Força (N)')
            zlabel(app.Grafico2, 'Z')
            app.Grafico1 = uiaxes(app.UIFigure);
            title(app.Grafico1, 'Pressão')
            xlabel(app.Grafico1, 'Tempo(s)')
            ylabel(app.Grafico1, 'Pressão (PSI)')
            zlabel(app.Grafico1, 'Z')
            app.IniciarAquisicao = uibutton(app.UIFigure, 'push');
            app.IniciarAquisicao.ButtonPushedFcn = createCallbackFcn(app, @IniciarAquisicaoButtonPushed, true);
            app.IniciarAquisicao.Text = 'Iniciar Aquisição';
            app.PararAquisicao = uibutton(app.UIFigure, 'push');
            app.PararAquisicao.ButtonPushedFcn = createCallbackFcn(app, @PararAquisicaoButtonPushed, true);
            app.PararAquisicao.Text = 'Parar Aquisição';
            app.Lamp = uilamp(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'leaa sem fundo.png');
            app.AquisiaodedadosparabancadadetesteestaticoLabel = uilabel(app.UIFigure);
            app.AquisiaodedadosparabancadadetesteestaticoLabel.Text = 'Aquisiçao de dados para bancada de teste estatico ';
            app.Calibrar = uibutton(app.UIFigure, 'push');
            app.Calibrar.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.Calibrar.Text = 'Calibrar ';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Design sem nome.png');
            app.Limpar = uibutton(app.UIFigure, 'push');
            app.Limpar.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.Limpar.Text = 'Limpar ';
            app.Tarar = uibutton(app.UIFigure, 'push');
            app.Tarar.ButtonPushedFcn = createCallbackFcn(app, @TararButtonPushed, true);
            app.Tarar.Text = 'Tarar';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Design sem nome.png');
            app.PanelCalibracao = uipanel(app.UIFigure);
            app.PanelCalibracao.Title = 'Passo 1';
            app.Passo1RemovaopesodabalanaLabel = uilabel(app.PanelCalibracao);
            app.Passo1RemovaopesodabalanaLabel.Text = 'Passo 1: Remova o peso da balança';
            app.BtnLerZero = uibutton(app.PanelCalibracao, 'push');
            app.BtnLerZero.Text = 'Ler Zero (Tara)';
            app.Passo2ColoqueumpesoconhecidokgLabel = uilabel(app.PanelCalibracao);
            app.Passo2ColoqueumpesoconhecidokgLabel.Text = 'Passo 2: Coloque um peso conhecido (kg):';
            app.PesoKgLabel = uilabel(app.PanelCalibracao);
            app.PesoKgLabel.Text = 'Peso (Kg)';
            app.InputPesoRef = uieditfield(app.PanelCalibracao, 'numeric');
            app.InputPesoRef.Value = 0.12;
            app.BtnCalcularFator = uibutton(app.PanelCalibracao, 'push');
            app.BtnCalcularFator.Text = 'Calcular e salvar ';
            app.BtnFecharCalibracao = uibutton(app.PanelCalibracao, 'push');
            app.BtnFecharCalibracao.Text = 'Cancelar';
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.UnidadePanel = uipanel(app.UIFigure);
            app.UnidadePanel.Title = 'Unidade';
            app.NCheckBox = uicheckbox(app.UnidadePanel);
            app.NCheckBox.Text = 'N';
            app.KgCheckBox = uicheckbox(app.UnidadePanel);
            app.KgCheckBox.Text = 'Kg';
            app.Gravar = uibutton(app.UIFigure, 'push');
            app.Gravar.ButtonPushedFcn = createCallbackFcn(app, @GravarButtonPushed, true);
            app.Gravar.Text = 'Iniciar Gravação ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppBancadaPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end