classdef AppBancadaPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        ResetarAplicativoMenu           matlab.ui.container.Menu
        AtualizarButton                 matlab.ui.control.Button
        PortaDropdown                   matlab.ui.control.DropDown
        SelecionarPortaUSBLabel         matlab.ui.control.Label
        PanelCalibracao                 matlab.ui.container.Panel
        BtnFecharCalibracao             matlab.ui.control.Button
        BtnCalcularFator                matlab.ui.control.Button
        InputPesoRef                    matlab.ui.control.NumericEditField
        PesoKgLabel                     matlab.ui.control.Label
        Passo2ColoqueumpesoconhecidokgLabel  matlab.ui.control.Label
        BtnLerZero                      matlab.ui.control.Button
        Passo1RemovaopesodabalanaLabel  matlab.ui.control.Label
        Image_3                         matlab.ui.control.Image
        Limpar                          matlab.ui.control.Button
        Image_2                         matlab.ui.control.Image
        AquisiaodedadosparabancadadetesteestaticoLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Panel                           matlab.ui.container.Panel
        Tarar                           matlab.ui.control.Button
        PararAquisicao                  matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        IniciarAquisicao                matlab.ui.control.Button
        Calibrar                        matlab.ui.control.Button
        StatusPanel                     matlab.ui.container.Panel
        PressaoMaximaField              matlab.ui.control.NumericEditField
        PressoMximaEditFieldLabel       matlab.ui.control.Label
        PressaoAtualField               matlab.ui.control.NumericEditField
        PressoAtualEditFieldLabel       matlab.ui.control.Label
        ForcaMaximaField                matlab.ui.control.NumericEditField
        ForaMximaEditFieldLabel         matlab.ui.control.Label
        ForcaAtualField                 matlab.ui.control.NumericEditField
        ForaAtualEditFieldLabel         matlab.ui.control.Label
        UnidadedeForaPanel              matlab.ui.container.Panel
        gCheckBox                       matlab.ui.control.CheckBox
        KgCheckBox                      matlab.ui.control.CheckBox
        NCheckBox                       matlab.ui.control.CheckBox
        Gravar                          matlab.ui.control.Button
        Grafico1                        matlab.ui.control.UIAxes
        Grafico2                        matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

properties (Access = private)
        % --- HARDWARE & CONTROLE ---
        DaqSession
        IsRunning logical = false
        
        % --- BUFFERS VISUAIS (Para o Gráfico na Tela) ---
        HistoryTime = [];
        HistoryForca = [];
        HistoryPressao = [];
        
        % --- GRAVAÇÃO (CSV - DADOS BRUTOS) ---
        IsRecording logical = false;
        RecTime = [];
        RecForce = [];
        RecPressure = [];
        
        % --- CONTROLE DE TEMPO ---
        TimeOffset = 0;   
        T0 = [];          % Referência do Zero Absoluto

        % --- CALIBRAÇÃO ---
        OffsetForca = 0;
        OffsetPressao = 0;
        
        % Fatores de Conversão
        FatorForca = 519.35;    
        FatorPressao = 250.0;   
        
        % --- UNIDADES VISUAIS ---
        MultUnidade = 1.0; % 1=N, 0.10197=Kg, 101.97=g
    end

    methods (Access = private)
        
        % --- 1. CONFIGURAÇÃO DO HARDWARE ---
        function ConfigurarDAQ(app)
            % Limpa sessão antiga
            if ~isempty(app.DaqSession) && ~isvalid(app.DaqSession), app.DaqSession = []; end
            if ~isempty(app.DaqSession), return; end

            daqreset; % Reset físico
            app.DaqSession = daq("ni");
            app.DaqSession.Rate = 1000; % 1000 Hz

            % Canal 0: Força
            ch1 = addinput(app.DaqSession, "Dev1", "ai0", "Voltage");
            ch1.TerminalConfig = "SingleEnded";
            
            % Canal 1: Pressão
            ch2 = addinput(app.DaqSession, "Dev1", "ai1", "Voltage");
            ch2.TerminalConfig = "SingleEnded";
            % -------------------------------------
            
            app.DaqSession.ScansAvailableFcn = @(src, event) processarDados(app, src, event);
            app.DaqSession.ScansAvailableFcnCount = 100;
        end

        % --- 2. CÉREBRO: GRAVA BRUTO, MOSTRA LIMPO ---
        function processarDados(app, src, ~)
            % A) Ler dados
            [data, timestamps] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");
            
            % Tempo Zero Absoluto
            if isempty(app.T0), app.T0 = timestamps(1); end
            tempoZero = timestamps - app.T0;
            if isempty(app.TimeOffset), app.TimeOffset = 0; end
            timeFinal = tempoZero + app.TimeOffset;
            
            % B) Engenharia RAW (Sempre em Newtons e PSI)
            valForce_RAW = (data(:, 1) - app.OffsetForca) * app.FatorForca;
            valPressure_RAW = (data(:, 2) - app.OffsetPressao) * app.FatorPressao;
            
            % C) GRAVAÇÃO (Salva o Bruto no Buffer)
            if app.IsRecording
                app.RecTime = [app.RecTime; timeFinal]; 
                app.RecForce = [app.RecForce; valForce_RAW];         
                app.RecPressure = [app.RecPressure; valPressure_RAW]; 
            end
            
            % D) TRATAMENTO VISUAL (Para Tela)
            valForce_Vis = valForce_RAW * app.MultUnidade;
            valPressure_Vis = valPressure_RAW; 
            
            % Zona Morta Visual
            DZ_Force = 5.0 * app.MultUnidade; 
            valForce_Vis(abs(valForce_Vis) < DZ_Force) = 0;
            valPressure_Vis(abs(valPressure_Vis) < 3.0) = 0; 
            
            % E) ATUALIZA MOSTRADORES (Status Panel)
            % ... (código para atualizar mostradores continua aqui) ...
            app.ForcaAtualField.Value = valForce_Vis(end);
            app.PressaoAtualField.Value = valPressure_Vis(end);
            
            maxF = max(valForce_Vis);
            if maxF > app.ForcaMaximaField.Value, app.ForcaMaximaField.Value = maxF; end
            
            maxP = max(valPressure_Vis);
            if maxP > app.PressaoMaximaField.Value, app.PressaoMaximaField.Value = maxP; end

            % F) Plotagem
            app.HistoryTime = [app.HistoryTime; timeFinal];
            app.HistoryForca = [app.HistoryForca; valForce_Vis];
            app.HistoryPressao = [app.HistoryPressao; valPressure_Vis];
            
            k = 50; 
            if length(app.HistoryForca) > k
                plotF = movmean(app.HistoryForca, k);
                plotP = movmean(app.HistoryPressao, k);
            else
                plotF = app.HistoryForca; plotP = app.HistoryPressao;
            end
            
            plot(app.Grafico2, app.HistoryTime, plotF, 'w', 'LineWidth', 2);
            plot(app.Grafico1, app.HistoryTime, plotP, 'g', 'LineWidth', 2);
            
            axis(app.Grafico2, 'auto'); axis(app.Grafico1, 'auto');
            app.Grafico2.XAxis.Exponent = 0; app.Grafico1.XAxis.Exponent = 0;
            xtickformat(app.Grafico2, '%.1f'); xtickformat(app.Grafico1, '%.1f');

            % ============================================================
            % WATCHDOG: STATUS DA LÂMPADA (AQUI ESTÁ A LÓGICA DAS CORES)
            % ============================================================
            
            % 1. Calcula a variação de sinal RAW no último pacote
            % Se o valor for > 0.005V (que está acima do ruído), o canal está vivo.
            sensibilidade_minima = 0.005; % ~5mV (Para detectar sinal real)
            
            % Verifica se a média do último pacote RAW é maior que o ruído mínimo
            isForceActive = mean(abs(valForce_RAW)) > sensibilidade_minima;
            isPressureActive = mean(abs(valPressure_RAW)) > sensibilidade_minima;
            
            if isForceActive && isPressureActive
                % Ambos estão lendo um sinal (> 5mV). Tudo OK.
                app.Lamp.Color = [0 1 0]; % VERDE
                
            elseif isForceActive || isPressureActive
                % Apenas um está lendo. Possível falha de sensor/cabo.
                app.Lamp.Color = [1 1 0]; % AMARELO/LARANJA (Aviso)
                
            else
                % Nenhum dado está vindo (ou ambos estão cravados em zero absoluto).
                app.Lamp.Color = [1 0 0]; % VERMELHO (Erro de comunicação)
            end
            % ============================================================
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
           try
                % 1. Configura o Hardware (Prepara o DAQ, mas não inicia)
                ConfigurarDAQ(app);
                
                % 2. AUTO-TARA (Lê o zero inicial)
                dataInicial = read(app.DaqSession, 10, "OutputFormat", "Matrix");
                
                app.OffsetForca = mean(dataInicial(:, 1));
                app.OffsetPressao = mean(dataInicial(:, 2));
                
                app.T0 = []; 
                app.TimeOffset = 0;
                
                % 3. CONFIGURAÇÃO PADRÃO DE UNIDADES
                app.NCheckBox.Value = true;   
                app.KgCheckBox.Value = false; 
                if isprop(app, 'gCheckBox'), app.gCheckBox.Value = false; end
                app.MultUnidade = 1.0; 
                
                % ============================================================
                % 4. MODO STANDBY (REMOÇÃO DO AUTO-START)
                % ============================================================
                % REMOVE: start(app.DaqSession, "Continuous");
                app.IsRunning = false; % O sistema NÃO está rodando
                app.Lamp.Color = [0.502 0.502 0.502]; % Lâmpada Cinza (Desligada)
                
                % 5. Habilita o INICIAR para o clique manual
                app.IniciarAquisicao.Enable = 'on'; 
                app.PararAquisicao.Enable = 'off';    
                
            catch ME
                % Se o DAQ falhar, deixa tudo parado e avisa
                app.Lamp.Color = [1 0 0];
                app.IniciarAquisicao.Enable = 'on';
                app.PararAquisicao.Enable = 'off';
                uialert(app.UIFigure, "Erro no Startup: Hardware não encontrado. Verifique conexões.", "Aviso");
            end
       
        end

        % Button pushed function: IniciarAquisicao
        function IniciarAquisicaoButtonPushed(app, event)

            % Se já estiver rodando, não faz nada
            if app.IsRunning
                return; 
            end
            
            try
                % 1. Limpa os vetores (Gráfico novo)
                app.HistoryTime = [];
                app.HistoryForca = [];
                app.HistoryPressao = [];
                
                % 2. Reseta o Relógio (Correção do "Espaço Vazio")
                app.TimeOffset = 0; 
                app.T0 = []; 
                
                % 3. ZERA OS MOSTRADORES DE PICO E STATUS (NOVO)
                % Garante que os números na tela sejam do teste ATUAL
                if isprop(app, 'ForcaMaximaField')
                    app.ForcaMaximaField.Value = 0;
                    app.PressaoMaximaField.Value = 0;
                    app.ForcaAtualField.Value = 0;
                    app.PressaoAtualField.Value = 0;
                end
                
                if isprop(app, 'CampoPicoForca')
                    app.CampoPicoForca.Value = 0;
                    app.CampoPicoPressao.Value = 0;
                end
                
                % 4. Garante que não comece gravando sozinho (Segurança)
                app.IsRecording = false;
                
                % 5. Configura e Inicia
                ConfigurarDAQ(app);
                
                app.IsRunning = true;
                app.IniciarAquisicao.Enable = 'off';
                app.PararAquisicao.Enable = 'on';
                
                % Comando final para iniciar a placa NI
                start(app.DaqSession, "Continuous");
                
            catch ME
                % Em caso de erro (cabo solto), destrava tudo e avisa
                app.IsRunning = false;
                app.IniciarAquisicao.Enable = 'on';
                app.PararAquisicao.Enable = 'off';
                uialert(app.UIFigure, "Erro ao iniciar hardware: " + ME.message, "Erro Crítico");
            end
        end

        % Button pushed function: PararAquisicao
        function PararAquisicaoButtonPushed(app, event)
% 1. Parar o Hardware imediatamente
            if app.IsRunning
                stop(app.DaqSession);
                app.IsRunning = false;
            end
            
            % 2. Verifica se o modo GRAVAÇÃO estava ligado
            estavaGravando = app.IsRecording;
            
            % 3. Desliga tudo e reseta os botões
            app.IsRecording = false; 
            
            % Reseta visual do botão Gravar
            app.Gravar.Text = "Iniciar Gravação"; 
            app.Gravar.BackgroundColor = [0.25 0.25 0.25]; 
            app.Gravar.Enable = 'on'; 
            
            app.IniciarAquisicao.Enable = 'on';
            app.PararAquisicao.Enable = 'off';
            
            % ============================================================
            % 4. LÓGICA DE SALVAMENTO E RELATÓRIO
            % ============================================================
            if estavaGravando
                try
                    % Segurança: Verifica se gravou algo
                    if isempty(app.RecTime)
                        uialert(app.UIFigure, "Buffer vazio.", "Erro");
                        return;
                    end
                    
                    % --- ANÁLISE RÁPIDA DE ENGENHARIA ---
                    
                    % 1. Prepara dados (Zera força negativa)
                    Force_Calc = app.RecForce;
                    Force_Calc(Force_Calc < 0) = 0; 
                    PicoF = max(Force_Calc);
                    
                    % 2. Detecção de Queima (Limiar de 5 Newtons)
                    IdxQueima = find(Force_Calc > 5.0); 
                    
                    if ~isempty(IdxQueima)
                        TempoQueima = app.RecTime(IdxQueima(end)) - app.RecTime(IdxQueima(1));
                        ImpulsoTotal = trapz(app.RecTime(IdxQueima), Force_Calc(IdxQueima));
                        
                        % ========================================================
                        % CLASSIFICAÇÃO COMPLETA (Baseada na Tabela)
                        % ========================================================
                        
                        % Nota: A lógica usa a precisão dos limites inferiores.
                        
                        if ImpulsoTotal > 20480.01
                            Classe = 'O (Nível 3)';
                        elseif ImpulsoTotal > 10240.01
                            Classe = 'N (Nível 3)';
                        elseif ImpulsoTotal > 5120.01
                            Classe = 'M (Nível 3)';
                        elseif ImpulsoTotal > 2560.01
                            Classe = 'L (Nível 2)';
                        elseif ImpulsoTotal > 1280.01
                            Classe = 'K (Nível 2)';
                        elseif ImpulsoTotal > 640.01
                            Classe = 'J (Nível 2)';
                        elseif ImpulsoTotal > 320.01
                            Classe = 'I (Nível 1)';
                        elseif ImpulsoTotal > 160.01
                            Classe = 'H (Nível 1)';
                        elseif ImpulsoTotal > 80.01
                            Classe = 'G (Média)';
                        elseif ImpulsoTotal > 40.01
                            Classe = 'F (Média)';
                        elseif ImpulsoTotal > 20.01
                            Classe = 'E (Média)';
                        elseif ImpulsoTotal > 10.01
                            Classe = 'D (Baixa)';
                        elseif ImpulsoTotal > 5.01
                            Classe = 'C (Baixa)';
                        elseif ImpulsoTotal > 2.51
                            Classe = 'B (Baixa)';
                        elseif ImpulsoTotal > 1.26
                            Classe = 'A (Baixa)';
                        elseif ImpulsoTotal > 0.626
                            Classe = '1/2 A (Micro)';
                        elseif ImpulsoTotal > 0.3126
                            Classe = '1/4 A (Micro)';
                        elseif ImpulsoTotal >= 0.00
                            Classe = '1/8 A (Micro)';
                        else
                            Classe = 'Sub-A/Ruído';
                        end
                        
                        % ========================================================
                    else
                        TempoQueima = 0; ImpulsoTotal = 0; Classe = 'Falha/Ruído';
                    end
                    
                    % 4. Salvar Arquivo (CSV)
                    T = table(app.RecTime, app.RecForce, app.RecPressure, 'VariableNames', {'Tempo','Forca','Pressao'});
                    nome = "Teste_" + string(datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss')) + ".csv";
                    [file, path] = uiputfile('*.csv', 'Salvar Dados', nome);
                    
                    if ~isequal(file, 0)
                        writetable(T, fullfile(path, file));
                        
                        % 5. MOSTRAR RELATÓRIO POPUP FINAL
                        msg = sprintf('DADOS SALVOS!\n\nImpulso Total: %.2f Ns\nClasse Estimada: %s\nTempo de Queima: %.2f s\nPico de Empuxo: %.2f N', ...
                            ImpulsoTotal, Classe, TempoQueima, PicoF);
                        uialert(app.UIFigure, msg, "Relatório de Teste", 'Icon', 'success');
                    else
                        uialert(app.UIFigure, "Salvamento cancelado pelo usuário. Dados perdidos.", "Aviso", 'Icon', 'warning');
                    end
                catch ME
                    uialert(app.UIFigure, "Erro Crítico ao finalizar: " + ME.message, "Erro");
                end
            end
            
            % 5. Volta a Lâmpada para o estado de repouso (Cinza)
            app.Lamp.Color = [0.502 0.502 0.502]; 
       
        end

        % Button pushed function: Calibrar
        function CalibrarButtonPushed(app, event)

    % 1. Segurança e Parada do DAQ
    if app.IsRunning
        stop(app.DaqSession);
        app.IsRunning = false;
        app.IniciarAquisicao.Enable = 'on';
        app.PararAquisicao.Enable = 'off';
    end

    % 2. Garante Hardware
    if isempty(app.DaqSession)
        ConfigurarDAQ(app);
    end

    % ============================================================
    % INTERFACE DARK MODE (VISUAL)
    % ============================================================
    
    % Cores do Tema
    CorFundo = [0.15 0.15 0.15]; % Cinza Escuro (Quase preto)
    CorTexto = [1.00 1.00 1.00]; % Branco
    CorInput = [0.25 0.25 0.25]; % Cinza Médio para caixas
    CorVerde = [0.00 0.80 0.00]; % Verde Neon para sucesso
    CorCinza = [0.50 0.50 0.50]; % Texto desabilitado
    
    % Janela Principal Escura
    calibFig = uifigure('Name', 'Assistente de Calibração', ...
        'Position', [500, 400, 450, 350], ...
        'Resize', 'off', ...
        'Color', CorFundo, ... % <--- AQUI MUDA O FUNDO
        'WindowStyle', 'modal'); 
        
    % Título
    uilabel(calibFig, 'Position', [20 310 400 30], ...
        'Text', 'Calibração de Célula de Carga', ...
        'FontSize', 16, 'FontWeight', 'bold', ...
        'FontColor', CorTexto); % Texto Branco

    % --- PASSO 1: INPUT ---
    uilabel(calibFig, 'Position', [20 270 250 22], ...
        'Text', '1) Informe a massa padrão (kg):', ...
        'FontColor', CorTexto);
    
    edtMassa = uieditfield(calibFig, 'numeric', ...
        'Position', [260 270 100 22], ...
        'Value', 5.400, ...
        'BackgroundColor', CorInput, ... % Fundo da caixa escuro
        'FontColor', CorTexto, ...       % Texto da caixa branco
        'Limits', [0.001 1000]);

    % --- PASSO 2: INSTRUÇÃO DE TARA ---
    lblTara = uilabel(calibFig, 'Position', [20 230 400 22], ...
        'Text', '2) Remova todo o peso da célula (Posição Vertical).', ...
        'FontColor', CorTexto); % Começa Branco (Ativo)

    % --- BARRA DE PROGRESSO ---
    barProgresso = uigauge(calibFig, 'linear', ...
        'Position', [20 190 410 30], ...
        'Limits', [0 100], ...
        'Value', 0, ...
        'BackgroundColor', CorInput); % Fundo da barra escuro
        
    % Status
    lblStatus = uilabel(calibFig, 'Position', [20 165 400 22], ...
        'Text', 'Aguardando início...', ...
        'FontColor', [0.8 0.8 0.8]); % Cinza claro

    % --- PASSO 3: INSTRUÇÃO DE CARGA ---
    lblCarga = uilabel(calibFig, 'Position', [20 130 400 22], ...
        'Text', '3) Insira a massa de calibração.', ...
        'Enable', 'off', ...
        'FontColor', CorCinza); % Começa cinza (inativo)

    % --- BOTÕES ---
    uibutton(calibFig, 'Position', [230 20 100 30], ...
        'Text', 'Cancelar', ...
        'BackgroundColor', [0.3 0.3 0.3], ... % Botão cinza
        'FontColor', 'w', ...
        'ButtonPushedFcn', @(o,e) close(calibFig));
        
    btnProximo = uibutton(calibFig, 'Position', [340 20 90 30], ...
        'Text', 'Próximo', ...
        'BackgroundColor', [0.0 0.45 0.74], ... % Azul
        'FontColor', 'w', ...
        'FontWeight', 'bold');

    % ============================================================
    % LÓGICA (ADAPTADA PARA DARK MODE)
    % ============================================================
    
    passoAtual = 1; 
    offsetSalvo = 0;
    
    btnProximo.ButtonPushedFcn = @(src, event) avancarPasso();
    
    function avancarPasso()
        
        % >>> ESTADO 1: TARA
        if passoAtual == 1
            btnProximo.Enable = 'off';
            edtMassa.Enable = 'off';
            lblStatus.Text = 'Lendo Zero (Tara)...';
            lblStatus.FontColor = CorTexto; % Branco
            
            dados_tara = [];
            for i = 1:10
                barProgresso.Value = i * 10;
                chunk = read(app.DaqSession, 100, "OutputFormat", "Matrix");
                dados_tara = [dados_tara; chunk(:,1)]; 
                drawnow; 
            end
            
            offsetSalvo = mean(dados_tara);
            app.OffsetForca = offsetSalvo;
            
            % Zera pressão (silencioso)
            chunkP = read(app.DaqSession, 10, "OutputFormat", "Matrix");
            app.OffsetPressao = mean(chunkP(:, 2)); 
            
            % Atualiza Interface
            barProgresso.Value = 0;
            lblTara.Enable = 'off';
            lblTara.Text = ['2) Tara OK (Zero: ' num2str(offsetSalvo, '%.4f') ' V)'];
            lblTara.FontColor = CorVerde; % Fica Verde quando conclui
            
            lblCarga.Enable = 'on'; 
            lblCarga.FontColor = CorTexto; % Fica Branco quando ativa
            lblCarga.FontWeight = 'bold';
            
            lblStatus.Text = 'Coloque o peso na célula e clique em Calibrar.';
            btnProximo.Text = 'Calibrar';
            btnProximo.Enable = 'on';
            passoAtual = 2;
            return;
        end
        
        % >>> ESTADO 2: LEITURA COM PESO
        if passoAtual == 2
            btnProximo.Enable = 'off';
            lblStatus.Text = 'Lendo Carga...';
            
            dados_peso = [];
            for i = 1:10
                barProgresso.Value = i * 10;
                chunk = read(app.DaqSession, 100, "OutputFormat", "Matrix");
                dados_peso = [dados_peso; chunk(:,1)];
                drawnow;
            end
            
            leituraComPeso = mean(dados_peso);
            massa = edtMassa.Value;
            ForcaN = massa * 9.80665;
            DeltaV = leituraComPeso - offsetSalvo;
            
            if abs(DeltaV) < 0.002
                 uialert(calibFig, 'Erro: Sem variação de voltagem.', 'Erro');
                 btnProximo.Enable = 'on';
                 barProgresso.Value = 0;
                 return;
            end
            
            novoFator = ForcaN / DeltaV;
            app.FatorForca = novoFator;
            
            barProgresso.Value = 100;
            lblStatus.Text = 'Sucesso! Fator Atualizado.';
            lblStatus.FontColor = CorVerde;
            
            msgFinal = sprintf('Calibração Perfeita!\n\nMassa: %.3f kg\nNovo Fator: %.2f N/V', ...
                massa, novoFator);
            
            uialert(calibFig, msgFinal, 'Concluído', 'Icon', 'success', ...
                'CloseFcn', @(o,e) close(calibFig));
        end
    end
        
     
        end

        % Button pushed function: Limpar
        function LimparButtonPushed(app, event)
           
            % 1. Zera memória visual
            app.HistoryTime = [];
            app.HistoryForca = [];
            app.HistoryPressao = [];
            
            % 2. Zera Relógio
            app.TimeOffset = 0; 
            app.T0 = [];
            
            % 3. Limpa Tela
            cla(app.Grafico1); 
            cla(app.Grafico2);
            
            % 4. ZERA OS CAMPOS DE STATUS E PICO (Se existirem)
            if isprop(app, 'ForcaAtualField')
                app.ForcaAtualField.Value = 0;
                app.PressaoAtualField.Value = 0;
                app.ForcaMaximaField.Value = 0;
                app.PressaoMaximaField.Value = 0;
            end
            
            if isprop(app, 'CampoPicoForca')
                app.CampoPicoForca.Value = 0;
                app.CampoPicoPressao.Value = 0;
            end
        
        end

        % Button pushed function: Tarar
        function TararButtonPushed(app, event)
            % 1. Verifica Hardware
            if isempty(app.DaqSession)
                ConfigurarDAQ(app);
            end

            % 2. Verifica se o teste está rodando agora
            estavaRodando = app.IsRunning;
            
            try
                % Se estiver rodando, PAUSA e SALVA O TEMPO
                if estavaRodando
                    stop(app.DaqSession);
                    
                    % --- AQUI ESTAVA FALTANDO! (CORREÇÃO DO BUG) ---
                    % Se já temos dados, salvamos o último segundo (ex: 10s)
                    % para somar depois e o gráfico não voltar pro zero.
                    if ~isempty(app.HistoryTime)
                        app.TimeOffset = app.HistoryTime(end);
                    end
                    % -----------------------------------------------
                end
                
                % 3. Lê o ZERO (Média de 500 amostras)
                data = read(app.DaqSession, 500, "OutputFormat", "Matrix");
                
                % Define os novos Offsets
                app.OffsetForca = mean(data(:, 1));
                app.OffsetPressao = mean(data(:, 2));
                
                % 4. Retoma o teste automaticamente
                if estavaRodando
                    start(app.DaqSession, "Continuous");
                else
                    uialert(app.UIFigure, "Sensores Zerados (Tara Definida).", "Sucesso");
                end
                
            catch ME
                uialert(app.UIFigure, "Erro ao tentar Tarar: " + ME.message, "Erro");
            end
        end

        % Button pushed function: Gravar
        function GravarButtonPushed(app, event)
           % 1. Segurança: Só grava se o sistema já estiver rodando
            if ~app.IsRunning
                uialert(app.UIFigure, "Clique em INICIAR primeiro.", "Erro");
                return;
            end
            
            % 2. Limpa os buffers de gravação (começa do zero)
            app.RecTime = [];
            app.RecForce = [];
            app.RecPressure = [];
            
            % 3. ATIVA A CHAVE DE GRAVAÇÃO (O MAIS IMPORTANTE)
            app.IsRecording = true;
            
            % 4. Muda o visual para VERMELHO (Feedback visual)
            app.Gravar.Text = "GRAVANDO...";
            app.Gravar.BackgroundColor = [1 0 0]; % Vermelho Vivo
            app.Gravar.FontColor = [1 1 1];       % Texto Branco
            app.Gravar.Enable = 'off';            % Trava o botão
       
        end

        % Value changed function: NCheckBox
        function NCheckBoxValueChanged(app, event)
           if app.NCheckBox.Value
                app.KgCheckBox.Value = false; 
                if isprop(app, 'gCheckBox'), app.gCheckBox.Value = false; end
                
                app.MultUnidade = 1.0; 
                app.Grafico2.YLabel.String = 'Força (N)';
            else
                app.NCheckBox.Value = true; % Obriga a ficar marcado
           end
        end

        % Value changed function: KgCheckBox
        function KgCheckBoxValueChanged(app, event)
           if app.KgCheckBox.Value
                app.NCheckBox.Value = false; 
                if isprop(app, 'gCheckBox'), app.gCheckBox.Value = false; end
                
                app.MultUnidade = 0.10197; 
                app.Grafico2.YLabel.String = 'Força (kgf)';
            else
                app.KgCheckBox.Value = true;
           end 
            
        end

        % Value changed function: gCheckBox
        function gCheckBoxValueChanged(app, event)
      if app.gCheckBox.Value
                app.NCheckBox.Value = false; 
                app.KgCheckBox.Value = false;
                
                app.MultUnidade = 101.97; 
                app.Grafico2.YLabel.String = 'Força (g)';
            else
                app.gCheckBox.Value = true;
      end     
        end

        % Menu selected function: ResetarAplicativoMenu
        function ResetarAplicativoMenuSelected(app, event)
            % Conteúdo da função ResetarAplicativoMenuSelected
    
    % 1. Parar a Aquisição (Se estiver rodando)
    if app.IsRunning
        stop(app.DaqSession);
    end
    
    % 2. Resetar Variáveis Críticas (Memória)
    app.IsRunning = false;
    app.IsRecording = false;
    app.HistoryTime = [];
    app.HistoryForca = [];
    app.HistoryPressao = [];
    app.RecTime = [];
    app.RecForce = [];
    app.RecPressure = [];
    
    % 3. Resetar Referências de Tempo e Calibração
    app.TimeOffset = 0;
    app.T0 = [];
    app.OffsetForca = 0; % Zera o offset para forçar nova leitura no recomeço
    app.OffsetPressao = 0;
    
    % 4. Limpar Visualmente a Tela
    cla(app.Grafico1); 
    cla(app.Grafico2);
    
    % Resetar Mostradores (Picos e Status)
    if isprop(app, 'ForcaAtualField')
        app.ForcaAtualField.Value = 0; app.ForcaMaximaField.Value = 0;
        app.PressaoAtualField.Value = 0; app.PressaoMaximaField.Value = 0;
    end
    if isprop(app, 'CampoPicoForca')
        app.CampoPicoForca.Value = 0; app.CampoPicoPressao.Value = 0;
    end
    
    % 5. Tenta Descarregar e Religar o Hardware (Recuperação)
    try
        daqreset; % Força o Windows a liberar a placa NI
        app.DaqSession = []; % Deleta a sessão
        
        ConfigurarDAQ(app); % Recria a sessão do zero
        
        % Auto-Tara (Lê o novo zero para começar limpo)
        dataInicial = read(app.DaqSession, 10, "OutputFormat", "Matrix");
        app.OffsetForca = mean(dataInicial(:, 1));
        app.OffsetPressao = mean(dataInicial(:, 2));
        
        % Inicia Monitoramento
        app.IsRunning = true;
        start(app.DaqSession, "Continuous");
        
        app.IniciarAquisicao.Enable = 'off';
        app.PararAquisicao.Enable = 'on';
        uialert(app.UIFigure, 'Sistema Resetado e Monitoramento Reiniciado.', 'Reset Concluído', 'Icon', 'info');

    catch ME
        % Se a placa não for encontrada após o reset (cabo solto)
        app.IniciarAquisicao.Enable = 'on';
        app.PararAquisicao.Enable = 'off';
        uialert(app.UIFigure, 'RESET FORÇADO! Hardware não encontrado. Clique em "Iniciar Aquisição" para tentar recarregar.', 'Erro de Recarga', 'Icon', 'error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.ResetarAplicativoMenu = uimenu(app.Menu);
            app.ResetarAplicativoMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetarAplicativoMenuSelected, true);
            app.ResetarAplicativoMenu.Text = 'Resetar Aplicativo';
            app.Grafico2 = uiaxes(app.UIFigure);
            title(app.Grafico2, 'Curva de Empuxo ')
            xlabel(app.Grafico2, 'Tempo (s)')
            ylabel(app.Grafico2, 'Força')
            zlabel(app.Grafico2, 'Z')
            app.Grafico1 = uiaxes(app.UIFigure);
            title(app.Grafico1, 'Pressão')
            xlabel(app.Grafico1, 'Tempo(s)')
            ylabel(app.Grafico1, 'Pressão (PSI)')
            zlabel(app.Grafico1, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Gravar = uibutton(app.Panel, 'push');
            app.Gravar.ButtonPushedFcn = createCallbackFcn(app, @GravarButtonPushed, true);
            app.Gravar.Text = 'Iniciar Gravação ';
            app.UnidadedeForaPanel = uipanel(app.Panel);
            app.UnidadedeForaPanel.Title = 'Unidade de Força';
            app.NCheckBox = uicheckbox(app.UnidadedeForaPanel);
            app.NCheckBox.ValueChangedFcn = createCallbackFcn(app, @NCheckBoxValueChanged, true);
            app.NCheckBox.Text = 'N';
            app.KgCheckBox = uicheckbox(app.UnidadedeForaPanel);
            app.KgCheckBox.ValueChangedFcn = createCallbackFcn(app, @KgCheckBoxValueChanged, true);
            app.KgCheckBox.Text = 'Kg';
            app.gCheckBox = uicheckbox(app.UnidadedeForaPanel);
            app.gCheckBox.ValueChangedFcn = createCallbackFcn(app, @gCheckBoxValueChanged, true);
            app.gCheckBox.Text = 'g';
            app.StatusPanel = uipanel(app.Panel);
            app.StatusPanel.Title = 'Status';
            app.ForaAtualEditFieldLabel = uilabel(app.StatusPanel);
            app.ForaAtualEditFieldLabel.Text = 'Força Atual ';
            app.ForcaAtualField = uieditfield(app.StatusPanel, 'numeric');
            app.ForaMximaEditFieldLabel = uilabel(app.StatusPanel);
            app.ForaMximaEditFieldLabel.Text = 'Força Máxima';
            app.ForcaMaximaField = uieditfield(app.StatusPanel, 'numeric');
            app.PressoAtualEditFieldLabel = uilabel(app.StatusPanel);
            app.PressoAtualEditFieldLabel.Text = 'Pressão Atual ';
            app.PressaoAtualField = uieditfield(app.StatusPanel, 'numeric');
            app.PressoMximaEditFieldLabel = uilabel(app.StatusPanel);
            app.PressoMximaEditFieldLabel.Text = 'Pressão  Máxima';
            app.PressaoMaximaField = uieditfield(app.StatusPanel, 'numeric');
            app.Calibrar = uibutton(app.Panel, 'push');
            app.Calibrar.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.Calibrar.Text = 'Calibrar ';
            app.IniciarAquisicao = uibutton(app.Panel, 'push');
            app.IniciarAquisicao.ButtonPushedFcn = createCallbackFcn(app, @IniciarAquisicaoButtonPushed, true);
            app.IniciarAquisicao.Text = 'Iniciar Aquisição';
            app.Lamp = uilamp(app.Panel);
            app.PararAquisicao = uibutton(app.Panel, 'push');
            app.PararAquisicao.ButtonPushedFcn = createCallbackFcn(app, @PararAquisicaoButtonPushed, true);
            app.PararAquisicao.Text = 'Parar Aquisição';
            app.Tarar = uibutton(app.Panel, 'push');
            app.Tarar.ButtonPushedFcn = createCallbackFcn(app, @TararButtonPushed, true);
            app.Tarar.Text = 'Tarar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'leaa sem fundo.png');
            app.AquisiaodedadosparabancadadetesteestaticoLabel = uilabel(app.UIFigure);
            app.AquisiaodedadosparabancadadetesteestaticoLabel.Text = 'Aquisiçao de dados para bancada de teste estatico ';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Design sem nome.png');
            app.Limpar = uibutton(app.UIFigure, 'push');
            app.Limpar.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.Limpar.Text = 'Limpar ';
            app.Image_3 = uiimage(app.UIFigure);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Design sem nome.png');
            app.PanelCalibracao = uipanel(app.UIFigure);
            app.PanelCalibracao.Title = 'Passo 1';
            app.Passo1RemovaopesodabalanaLabel = uilabel(app.PanelCalibracao);
            app.Passo1RemovaopesodabalanaLabel.Text = 'Passo 1: Remova o peso da balança';
            app.BtnLerZero = uibutton(app.PanelCalibracao, 'push');
            app.BtnLerZero.Text = 'Ler Zero (Tara)';
            app.Passo2ColoqueumpesoconhecidokgLabel = uilabel(app.PanelCalibracao);
            app.Passo2ColoqueumpesoconhecidokgLabel.Text = 'Passo 2: Coloque um peso conhecido (kg):';
            app.PesoKgLabel = uilabel(app.PanelCalibracao);
            app.PesoKgLabel.Text = 'Peso (Kg)';
            app.InputPesoRef = uieditfield(app.PanelCalibracao, 'numeric');
            app.InputPesoRef.Value = 0.12;
            app.BtnCalcularFator = uibutton(app.PanelCalibracao, 'push');
            app.BtnCalcularFator.Text = 'Calcular e salvar ';
            app.BtnFecharCalibracao = uibutton(app.PanelCalibracao, 'push');
            app.BtnFecharCalibracao.Text = 'Cancelar';
            app.SelecionarPortaUSBLabel = uilabel(app.UIFigure);
            app.SelecionarPortaUSBLabel.Text = {'Selecionar Porta USB:'; ''};
            app.PortaDropdown = uidropdown(app.UIFigure);
            app.AtualizarButton = uibutton(app.UIFigure, 'push');
            app.AtualizarButton.Text = 'Atualizar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppBancadaPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end