classdef AppBancadaPrincipal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ForcaAtualField_2           matlab.ui.control.NumericEditField
        EmpuxoAtualLabel_2          matlab.ui.control.Label
        NLabel_12                   matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        EditField_12                matlab.ui.control.NumericEditField
        Button_21                   matlab.ui.control.Button
        NLabel_11                   matlab.ui.control.Label
        TemperaturaMximaLabel       matlab.ui.control.Label
        EditField_11                matlab.ui.control.NumericEditField
        Button_20                   matlab.ui.control.Button
        PSILabel                    matlab.ui.control.Label
        PressoMximaLabel            matlab.ui.control.Label
        EditField_10                matlab.ui.control.NumericEditField
        Button_19                   matlab.ui.control.Button
        PSILabel_2                  matlab.ui.control.Label
        PressoAtualLabel            matlab.ui.control.Label
        EditField_9                 matlab.ui.control.NumericEditField
        Button_18                   matlab.ui.control.Button
        NsLabel                     matlab.ui.control.Label
        ImpulsototalLabel           matlab.ui.control.Label
        EditField_8                 matlab.ui.control.NumericEditField
        Button_17                   matlab.ui.control.Button
        NLabel_7                    matlab.ui.control.Label
        TemperaturaatualLabel       matlab.ui.control.Label
        EditField_7                 matlab.ui.control.NumericEditField
        Button_16                   matlab.ui.control.Button
        NLabel_2                    matlab.ui.control.Label
        EmpuxoMximoLabel_2          matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        Button_10                   matlab.ui.control.Button
        NLabel                      matlab.ui.control.Label
        EmpuxoAtualLabel_3          matlab.ui.control.Label
        EditField                   matlab.ui.control.NumericEditField
        Button_9                    matlab.ui.control.Button
        MONITORAMENTOLabel          matlab.ui.control.Label
        gfCheckBox                  matlab.ui.control.CheckBox
        kgfCheckBox                 matlab.ui.control.CheckBox
        NCheckBox_2                 matlab.ui.control.CheckBox
        UNIDADEDEFORALabel          matlab.ui.control.Label
        LimparButton                matlab.ui.control.Button
        TararButton                 matlab.ui.control.Button
        CalibrarButton              matlab.ui.control.Button
        INICIARButton_2             matlab.ui.control.Button
        INICIARButton               matlab.ui.control.Button
        CONTROLELabel               matlab.ui.control.Label
        CONECTARButton              matlab.ui.control.Button
        Lamp_2                      matlab.ui.control.Lamp
        CONECTADOButton             matlab.ui.control.Button
        PORTASERIALDropDown         matlab.ui.control.DropDown
        CONEXOLabel                 matlab.ui.control.Label
        Button_5                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        MonitoramentoPanel          matlab.ui.container.Panel
        EmpuxoMedioField            matlab.ui.control.NumericEditField
        EmpuxomdioLabel             matlab.ui.control.Label
        ImpulsoTotalField           matlab.ui.control.NumericEditField
        ImpulsototalEditFieldLabel  matlab.ui.control.Label
        PressaoMaximaField          matlab.ui.control.NumericEditField
        PressoMximaEditFieldLabel   matlab.ui.control.Label
        PressaoAtualField           matlab.ui.control.NumericEditField
        PressoAtualEditFieldLabel   matlab.ui.control.Label
        ForcaMaximaField            matlab.ui.control.NumericEditField
        EmpuxoMximoLabel            matlab.ui.control.Label
        ForcaAtualField             matlab.ui.control.NumericEditField
        EmpuxoAtualLabel            matlab.ui.control.Label
        ArquivoPanel                matlab.ui.container.Panel
        AQUISIODEDADOSBANCADADETESTEESTTICOLabel  matlab.ui.control.Label
        SISTEMADEINSTRUMENTAOLabel  matlab.ui.control.Label
        StatusEditField             matlab.ui.control.EditField
        StatusEditFieldLabel        matlab.ui.control.Label
        AtualizarPortaButton        matlab.ui.control.Button
        DropDownDevID               matlab.ui.control.DropDown
        SelecionarPortaUSBLabel     matlab.ui.control.Label
        Limpar                      matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        Tarar                       matlab.ui.control.Button
        PararAquisicao              matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        IniciarAquisicao            matlab.ui.control.Button
        Calibrar                    matlab.ui.control.Button
        UnidadedeForaPanel          matlab.ui.container.Panel
        gCheckBox                   matlab.ui.control.CheckBox
        KgCheckBox                  matlab.ui.control.CheckBox
        NCheckBox                   matlab.ui.control.CheckBox
        Gravar                      matlab.ui.control.Button
        Grafico1_2                  matlab.ui.control.UIAxes
        Grafico2                    matlab.ui.control.UIAxes
        Grafico1                    matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

properties (Access = private)

    % --- HARDWARE & CONTROLE ---
    DaqSession
    IsRunning logical = false;

    % --- BUFFERS VISUAIS ---
    HistoryTime = [];
    HistoryForca = [];
    HistoryPressao = [];

    % --- GRAVAÇÃO ---
    IsRecording logical = false;
    RecTime = [];
    RecForce = [];
    RecPressure = [];

    % --- TEMPO ---
    TimeOffset = 0;
    T0 = [];

    % --- CALIBRAÇÃO ---
    OffsetForca = 0;
    OffsetPressao = 0;

    % ===========================================================
    % Fatores de conversão (carregado do arquivo TXT no startup)
    % ===========================================================
    FatorForca = 519.35;     % valor padrão fallback
    FatorPressao = 250.0;

    % --- DEVICE SELECIONADO ---
    DeviceID = "Dev1";

    % --- UNIDADES (N, kgf, g) ---
    MultUnidade = 1.0;

end
   methods (Access = private)

% ============================================================
% CONFIGURAR DAQ — TOTALMENTE CORRIGIDO
% ============================================================
function ConfigurarDAQ(app)

    if ~isempty(app.DaqSession) && ~isvalid(app.DaqSession)
        app.DaqSession = [];
    end

    if ~isempty(app.DaqSession)
        return;
    end

    try
        daqreset;
        app.DaqSession = daq("ni");
        app.DaqSession.Rate = 1000;  

        % CANAL 0 — FORÇA
        ch1 = addinput(app.DaqSession, app.DeviceID, "ai0", "Voltage");
        ch1.TerminalConfig = "SingleEnded";

        % CANAL 1 — PRESSÃO
        ch2 = addinput(app.DaqSession, app.DeviceID, "ai1", "Voltage");
        ch2.TerminalConfig = "SingleEnded";

        % CALLBACK
        app.DaqSession.ScansAvailableFcn = @(src,evt) processarDados(app,src,evt);
        app.DaqSession.ScansAvailableFcnCount = 100;

    catch ME
        warning("Erro ao configurar DAQ: " + ME.message);
        app.DaqSession = [];
    end

end


% ============================================================
% ATUALIZAR LISTA DE PORTAS — PROFISSIONAL
% ============================================================
function atualizarListaDePortas(app)
    try
        portas = serialportlist("available");

        if isempty(portas)
            app.DropDownDevID.Items = {"Nenhuma porta encontrada"};
        else
            app.DropDownDevID.Items = portas;
        end

    catch
        app.DropDownDevID.Items = {"Erro ao buscar portas"};
    end
end


% ============================================================
% PROCESSAR DADOS — CÉREBRO DO SISTEMA
% ============================================================
function processarDados(app, src, ~)

    % WATCHDOG — DESCONEXÃO DO DAQ
    try
        if isempty(app.DaqSession) || ~isvalid(app.DaqSession)
            error("DAQ caiu");
        end

        tmp = src.ScansAvailableFcnCount; %#ok
    catch
        app.IsRunning = false;

        app.StatusEditField.Value = "Desconectado";
        app.StatusEditField.BackgroundColor = [1 0.4 0.4];
        app.Lamp.Color = [1 0 0];

        app.IniciarAquisicao.Enable = "on";
        app.PararAquisicao.Enable = "off";

        daqreset;
        app.DaqSession = [];

        return;
    end


    % ============================================================
    % A) LEITURA
    % ============================================================
    [data, timestamps] = read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");

    if isempty(app.T0)
        app.T0 = timestamps(1);
    end

    tempoZero = timestamps - app.T0;
    timeFinal = tempoZero + app.TimeOffset;


    % ============================================================
    % B) ENGENHARIA (RAW)
    % ============================================================
    valForce_RAW    = (data(:,1) - app.OffsetForca)   * app.FatorForca;
    valPressure_RAW = (data(:,2) - app.OffsetPressao) * app.FatorPressao;


    % ============================================================
    % C) GRAVAÇÃO
    % ============================================================
    if app.IsRecording
        app.RecTime     = [app.RecTime; timeFinal];
        app.RecForce    = [app.RecForce; valForce_RAW];
        app.RecPressure = [app.RecPressure; valPressure_RAW];
    end


    % ============================================================
    % D) VALORES PARA TELA
    % ============================================================
    valForce_Vis    = valForce_RAW * app.MultUnidade;
    valPressure_Vis = valPressure_RAW;

    valForce_Vis(abs(valForce_Vis) < 5 * app.MultUnidade) = 0;
    valPressure_Vis(abs(valPressure_Vis) < 3) = 0;


    % ============================================================
    % E) MOSTRADORES
    % ============================================================
    app.ForcaAtualField.Value = valForce_Vis(end);
    app.PressaoAtualField.Value = valPressure_Vis(end);

    if valForce_Vis(end) > app.ForcaMaximaField.Value
        app.ForcaMaximaField.Value = valForce_Vis(end);
    end

    if valPressure_Vis(end) > app.PressaoMaximaField.Value
        app.PressaoMaximaField.Value = valPressure_Vis(end);
    end


    % ============================================================
    % F) HISTÓRICO E PLOT
    % ============================================================
    app.HistoryTime     = [app.HistoryTime; timeFinal];
    app.HistoryForca    = [app.HistoryForca; valForce_Vis];
    app.HistoryPressao  = [app.HistoryPressao; valPressure_Vis];

    k = 50;

    if length(app.HistoryForca) > k
        plotF = movmean(app.HistoryForca, k);
        plotP = movmean(app.HistoryPressao, k);
    else
        plotF = app.HistoryForca;
        plotP = app.HistoryPressao;
    end

    plot(app.Grafico2, app.HistoryTime, plotF, 'w', 'LineWidth', 2);
    plot(app.Grafico1, app.HistoryTime, plotP, 'g', 'LineWidth', 2);

    axis(app.Grafico2,'auto'); 
    axis(app.Grafico1,'auto');

    xtickformat(app.Grafico2,'%.1f');
    xtickformat(app.Grafico1,'%.1f');


    % ============================================================
    % G) LÂMPADA — STATUS DO SINAL
    % ============================================================
    sens = 0.005;

    isF = mean(abs(valForce_RAW))    > sens;
    isP = mean(abs(valPressure_RAW)) > sens;

    if isF && isP
        app.Lamp.Color = [0 1 0]; % Verde
    elseif isF || isP
        app.Lamp.Color = [1 1 0]; % Amarelo
    else
        app.Lamp.Color = [1 0 0]; % Vermelho
    end

    % ============================================================
% H) IMPULSO TOTAL — EM TEMPO REAL
% ============================================================
if length(app.HistoryTime) > 5

    F = app.HistoryForca;
    F(F < 0) = 0;

    tempo = app.HistoryTime;

    impulso = trapz(tempo, F);

    if isprop(app, "ImpulsoTotalField")
        app.ImpulsoTotalField.Value = impulso;
    end
   
end
 % ============================================================
    % I) EMPUXO MÉDIO — EM TEMPO REAL
    % ============================================================
if length(app.HistoryTime) > 5   % Garantir dados suficientes

        tempo = app.HistoryTime;
        F = app.HistoryForca;

        % Apenas empuxo positivo conta
        F(F < 0) = 0;

        % Evita divisão por zero
        duracao = tempo(end) - tempo(1);
        if duracao > 0
            Fmedio = trapz(tempo, F) / duracao;
        else
            Fmedio = 0;
        end

        % Atualiza o campo visual (se existir)
        if isprop(app, "EmpuxoMedioField")
            app.EmpuxoMedioField.Value = Fmedio;
        end
 end

end


% ============================================================
% STATUS DO HARDWARE
% ============================================================
function UpdateStatus(app)

    % Se não existe sessão → Desconectado
    if isempty(app.DaqSession) || ~isvalid(app.DaqSession)

        app.StatusEditField.Value = "Desconectado";
        app.StatusEditField.BackgroundColor = [0.149 0.149 0.149];  % Fundo normal do app
        app.StatusEditField.FontColor = [1 0 0];                    % Texto vermelho
        app.Lamp.Color = [1 0 0];                                   % Lâmpada vermelha
        return;
    end

    % Se chegou aqui, está conectado
    app.StatusEditField.Value = "Conectado";
    app.StatusEditField.BackgroundColor = [0.149 0.149 0.149];      % Fundo normal (cinza escuro)
    app.StatusEditField.FontColor = [0 1 0];                        % Texto verde
    app.Lamp.Color = [0 1 0];                                       % Lâmpada verde

end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
try
    % ============================================================
    % 1. CONFIGURA HARDWARE (somente prepara, não inicia)
    % ============================================================
    ConfigurarDAQ(app);

    % Atualiza status após tentativa de configuração
    UpdateStatus(app);

    % Se mesmo após tentar configurar não existir DAQ → aborta
    if isempty(app.DaqSession) || ~isvalid(app.DaqSession)
        error("DAQ não encontrado");
    end

    % ============================================================
    % 2. AUTO-TARA (Zero Inicial)
    % ============================================================
    try
        dataInicial = read(app.DaqSession, 10, "OutputFormat", "Matrix");
    catch
        error("Falha ao ler o zero inicial (DAQ não responde)");
    end

    app.OffsetForca   = mean(dataInicial(:,1));
    app.OffsetPressao = mean(dataInicial(:,2));

    % Reset de tempo
    app.T0 = [];
    app.TimeOffset = 0;

    % ============================================================
    % 3. CARREGAR FATOR PERMANENTE DO ARQUIVO TXT
    % ============================================================
    try
        fatorSalvo = load("fator_forca.txt");

        if isempty(fatorSalvo) || ~isnumeric(fatorSalvo) || fatorSalvo <= 0
            warning("Arquivo fator_forca.txt inválido — usando valor padrão.");
        else
            app.FatorForca = fatorSalvo;    % aplica valor salvo
        end

    catch
        % Se não existir o arquivo, mantém o valor padrão do código.
    end


    % ============================================================
    % 4. CONFIGURAÇÃO PADRÃO DE UNIDADES
    % ============================================================
    app.NCheckBox.Value = true;
    app.KgCheckBox.Value = false;
    if isprop(app, 'gCheckBox'), app.gCheckBox.Value = false; end

    % Força o callback para atualizar eixo e unidade
    NCheckBoxValueChanged(app, []);

    app.MultUnidade = 1.0;   % Newton

    % ============================================================
    % 5. MODO STANDBY
    % ============================================================
    app.IsRunning = false;
    app.Lamp.Color = [0.502 0.502 0.502];   % Lâmpada cinza (ocioso)

    % ============================================================
    % 6. Botões iniciais
    % ============================================================
    app.IniciarAquisicao.Enable = 'on';
    app.PararAquisicao.Enable   = 'off';

catch ME

    % ============================================================
    % ERRO DE HARDWARE / INICIALIZAÇÃO
    % ============================================================
    app.Lamp.Color = [1 0 0];  % Vermelho
    app.IniciarAquisicao.Enable = 'on';
    app.PararAquisicao.Enable   = 'off';

    UpdateStatus(app);

    uialert(app.UIFigure, ...
        "Erro no Startup:\n" + ME.message + ...
        "\n\nVerifique a conexão USB do DAQ.", ...
        "Aviso");
end

% ============================================================
% 7. Atualiza lista de portas ao iniciar
% ============================================================
try
    app.atualizarListaDePortas();
catch
    % Evita falhas por erro na leitura das portas
end

        end

        % Button pushed function: IniciarAquisicao
        function IniciarAquisicaoButtonPushed(app, event)


% ============================================================
% 1. PREVENÇÃO DE DUPLA INICIALIZAÇÃO
% ============================================================
if app.IsRunning
    return;
end

try
    % ============================================================
    % 2. LIMPA MEMÓRIA VISUAL E ESTADOS
    % ============================================================
    app.HistoryTime = [];
    app.HistoryForca = [];
    app.HistoryPressao = [];

    app.TimeOffset = 0;
    app.T0 = [];

    app.ForcaMaximaField.Value = 0;
    app.PressaoMaximaField.Value = 0;
    app.ForcaAtualField.Value = 0;
    app.PressaoAtualField.Value = 0;

    app.IsRecording = false;

    % ============================================================
    % 3. (RE)INICIALIZA DAQ USANDO SELEÇÃO DO USUÁRIO
    % ============================================================
    app.DaqSession = [];      % força reconstrução
    ConfigurarDAQ(app);

    if isempty(app.DaqSession) || ~isvalid(app.DaqSession)
        uialert(app.UIFigure, "DAQ não encontrado ou falha na conexão.", "Erro");
        return;
    end

    % ============================================================
    % 4. AJUSTE DE ESTADO VISUAL
    % ============================================================
    app.IsRunning = true;

    app.IniciarAquisicao.Enable = 'off';
    app.PararAquisicao.Enable = 'on';

    app.StatusEditField.Value = "Conectado";
    app.StatusEditField.BackgroundColor = [0.149 0.149 0.149];  % Fundo normal
    app.StatusEditField.FontColor = [0 1 0];                    % Texto verde
    app.Lamp.Color = [0 1 0];                                   % Lâmpada verde

    % ============================================================
    % 5. INICIAR AQUISIÇÃO CONTÍNUA
    % ============================================================
    start(app.DaqSession, "Continuous");

catch ME
    % ============================================================
    % 6. TRATAMENTO DE ERRO ROBUSTO
    % ============================================================
    app.IsRunning = false;

    app.IniciarAquisicao.Enable = 'on';
    app.PararAquisicao.Enable   = 'off';

    app.StatusEditField.Value = "Desconectado";
    app.StatusEditField.BackgroundColor = [1.0 0.6 0.6];   % vermelho claro

    uialert(app.UIFigure, "Erro ao iniciar: " + ME.message, "Erro Crítico");
end
        end

        % Button pushed function: PararAquisicao
        function PararAquisicaoButtonPushed(app, event)
% ============================================================
% 1. PARAR HARDWARE
% ============================================================
if app.IsRunning
    try
        stop(app.DaqSession);
    catch
        % Ignore erro se a sessão já estiver morta
    end
    app.IsRunning = false;
end

% ============================================================
% 2. ATUALIZA STATUS VISUAL
% ============================================================
app.StatusEditField.Value = "Parado";
app.StatusEditField.BackgroundColor = [0.149 0.149 0.149];
app.StatusEditField.FontColor = [1 0.5 0];   % Laranja
app.Lamp.Color = [1 0.5 0];                  % Lâmpada laranja

% Se estava gravando, vamos salvar depois
estavaGravando = app.IsRecording;

% Reset de flags
app.IsRecording = false;

% Botão Gravar volta ao padrão
app.Gravar.Text = "Iniciar Gravação";
app.Gravar.BackgroundColor = [0.25 0.25 0.25];
app.Gravar.FontColor = [1 1 1];
app.Gravar.Enable = 'on';

% Botões de aquisição
app.IniciarAquisicao.Enable = 'on';
app.PararAquisicao.Enable = 'off';

% ============================================================
% 3. SE NÃO ESTAVA GRAVANDO, ENCERRA AQUI
% ============================================================
if ~estavaGravando
    app.Lamp.Color = [0.502 0.502 0.502];  % Cinza (repouso)
    return;
end

% ============================================================
% 4. PROCESSAMENTO E SALVAMENTO DO TESTE
% ============================================================
try
    if isempty(app.RecTime)
        uialert(app.UIFigure, "Buffer vazio. Nada a salvar.", "Erro");
        return;
    end

    % ------------- PRÉ-PROCESSAMENTO -------------
    Force_Calc = app.RecForce;
    Force_Calc(Force_Calc < 0) = 0;  % zera valores negativos
    PicoF = max(Force_Calc);

    % Detecta intervalo de queima (> 5 N)
    IdxQueima = find(Force_Calc > 5.0);

 if isempty(IdxQueima)
        TempoQueima = 0;
        ImpulsoTotal = 0;
        Classe = "Falha/Ruído";
    else
    TempoQueima = app.RecTime(IdxQueima(end)) - app.RecTime(IdxQueima(1));
    ImpulsoTotal = trapz(app.RecTime(IdxQueima), Force_Calc(IdxQueima));
% ===================== CLASSIFICAÇÃO NAR =====================
    if ImpulsoTotal > 20480.01
        Classe = "O (Nível 3)";
    elseif ImpulsoTotal > 10240.01
        Classe = "N (Nível 3)";
    elseif ImpulsoTotal > 5120.01
        Classe = "M (Nível 3)";
    elseif ImpulsoTotal > 2560.01
        Classe = "L (Nível 2)";
    elseif ImpulsoTotal > 1280.01
        Classe = "K (Nível 2)";
    elseif ImpulsoTotal > 640.01
        Classe = "J (Nível 2)";
    elseif ImpulsoTotal > 320.01
        Classe = "I (Nível 1)";
    elseif ImpulsoTotal > 160.01
        Classe = "H (Nível 1)";
    elseif ImpulsoTotal > 80.01
        Classe = "G (Média)";
    elseif ImpulsoTotal > 40.01
        Classe = "F (Média)";
    elseif ImpulsoTotal > 20.01
        Classe = "E (Média)";
    elseif ImpulsoTotal > 10.01
        Classe = "D (Baixa)";
    elseif ImpulsoTotal > 5.01
        Classe = "C (Baixa)";
    elseif ImpulsoTotal > 2.51
        Classe = "B (Baixa)";
    elseif ImpulsoTotal > 1.26
        Classe = "A (Baixa)";
    elseif ImpulsoTotal > 0.626
        Classe = "1/2 A (Micro)";
    elseif ImpulsoTotal > 0.3126
        Classe = "1/4 A (Micro)";
    else
        Classe = "1/8 A (Micro)";
    end
end
 

   % ============================================================
% 5. SALVAÇÃO DO ARQUIVO CSV
% ============================================================
T = table(app.RecTime, app.RecForce, app.RecPressure, ...
    'VariableNames', {'Tempo','Forca','Pressao'});

nomePadrao = "Teste_" + string(datetime('now', 'Format', 'yyyy-MM-dd_HH-mm-ss')) + ".csv";

[file, path] = uiputfile("*.csv", "Salvar Dados", nomePadrao);

if isequal(file, 0)
    uialert(app.UIFigure, ...
        "Salvamento cancelado. Os dados da gravação foram descartados.", ...
        "Aviso", "Icon", "warning");

else
    writetable(T, fullfile(path, file));

    % ============================================================
    % 6. POPUP FINAL DO RELATÓRIO (AGORA COM PICO DE PRESSÃO)
    % ============================================================
    PicoPressao = app.PressaoMaximaField.Value;  % <-- adicionamos isto

    msg = sprintf( ...
        ['DADOS SALVOS!\n\n' ...
         'Impulso Total: %.2f Ns\n' ...
         'Classe Estimada: %s\n' ...
         'Tempo de Queima: %.3f s\n' ...
         'Pico de Empuxo: %.2f N\n' ...
         'Pico de Pressão: %.2f PSI\n'], ...
         ImpulsoTotal, Classe, TempoQueima, PicoF, PicoPressao);

    uialert(app.UIFigure, msg, ...
        "Relatório de Teste", ...
        "Icon", "success", ...
        "Interpreter", "none");   % popup mais largo
end

catch ME
    uialert(app.UIFigure, "Erro ao finalizar gravação: " + ME.message, "Erro");
end
% ============================================================
% 7. VOLTA PARA REPOUSO
% ============================================================
app.Lamp.Color = [0.502 0.502 0.502]; 
       
        end

        % Button pushed function: Calibrar
        function CalibrarButtonPushed(app, event)

    % ============================================================
% 1. PARAR HARDWARE (seguro)
% ============================================================
if app.IsRunning
    try
        stop(app.DaqSession);
    catch
        % ignora erro se o DAQ já estiver desconectado
    end
    app.IsRunning = false;
    app.IniciarAquisicao.Enable = 'on';
    app.PararAquisicao.Enable = 'off';
end

% ============================================================
% 2. GARANTIR QUE O DAQ EXISTE E ESTÁ VÁLIDO
% ============================================================
if isempty(app.DaqSession) || ~isvalid(app.DaqSession)
    app.DaqSession = [];
    ConfigurarDAQ(app);

    if isempty(app.DaqSession)
        uialert(app.UIFigure, ...
            "DAQ não encontrado. Conecte a interface NI e tente novamente.", ...
            "Erro de Hardware");
        return;
    end
end

% ============================================================
% 3. CONFIGURAÇÃO DO DARK MODE (INTERFACE)
% ============================================================
CorFundo = [0.15 0.15 0.15];
CorTexto = [1.00 1.00 1.00];
CorInput = [0.25 0.25 0.25];
CorVerde = [0.00 0.80 0.00];
CorCinza = [0.50 0.50 0.50];

calibFig = uifigure('Name', 'Assistente de Calibração', ...
    'Position', [500, 400, 450, 350], ...
    'Resize', 'off', ...
    'Color', CorFundo, ...
    'WindowStyle', 'modal');

% ------------------------------------------------------------
% Componentes da janela
% ------------------------------------------------------------

uilabel(calibFig, 'Position', [20 310 400 30], ...
    'Text', 'Calibração de Célula de Carga', ...
    'FontSize', 16, 'FontWeight', 'bold', ...
    'FontColor', CorTexto);

uilabel(calibFig, 'Position', [20 270 250 22], ...
    'Text', '1) Informe a massa padrão (kg):', ...
    'FontColor', CorTexto);

edtMassa = uieditfield(calibFig, 'numeric', ...
    'Position', [260 270 100 22], ...
    'Value', 5.400, ...
    'BackgroundColor', CorInput, ...
    'FontColor', CorTexto, ...
    'Limits', [0.001 1000]);

lblTara = uilabel(calibFig, 'Position', [20 230 400 22], ...
    'Text', '2) Remova todo o peso da célula (posição vertical).', ...
    'FontColor', CorTexto);

barProgresso = uigauge(calibFig, 'linear', ...
    'Position', [20 190 410 30], ...
    'Limits', [0 100], ...
    'Value', 0, ...
    'BackgroundColor', CorInput);

lblStatus = uilabel(calibFig, 'Position', [20 165 400 22], ...
    'Text', 'Aguardando início...', ...
    'FontColor', [0.8 0.8 0.8]);

lblCarga = uilabel(calibFig, 'Position', [20 130 400 22], ...
    'Text', '3) Insira a massa de calibração.', ...
    'Enable', 'off', ...
    'FontColor', CorCinza);

uibutton(calibFig, 'Position', [230 20 100 30], ...
    'Text', 'Cancelar', ...
    'BackgroundColor', [0.3 0.3 0.3], ...
    'FontColor', 'w', ...
    'ButtonPushedFcn', @(o,e) close(calibFig));

btnProximo = uibutton(calibFig, 'Position', [340 20 90 30], ...
    'Text', 'Próximo', ...
    'BackgroundColor', [0.0 0.45 0.74], ...
    'FontColor', 'w', ...
    'FontWeight', 'bold');


% ============================================================
% 4. LÓGICA DO WIZARD DE CALIBRAÇÃO
% ============================================================

passoAtual = 1;
offsetSalvo = 0;

btnProximo.ButtonPushedFcn = @(src, event) avancarPasso();

    function avancarPasso()

        % ------------------------------------------------------------
        % PASSO 1 – Leitura de Tara
        % ------------------------------------------------------------
        if passoAtual == 1

            btnProximo.Enable = 'off';
            edtMassa.Enable = 'off';
            lblStatus.Text = 'Lendo Zero (Tara)...';
            lblStatus.FontColor = CorTexto;

            dados_tara = [];

            for i = 1:10
                barProgresso.Value = i * 10;
                try
                    chunk = read(app.DaqSession, 100, "OutputFormat", "Matrix");
                catch
                    uialert(calibFig, ...
                        "A leitura falhou. O DAQ pode ter sido desconectado.", ...
                        "Erro de Hardware");
                    close(calibFig);
                    return;
                end

                dados_tara = [dados_tara; chunk(:,1)];
                drawnow;
            end

            offsetSalvo = mean(dados_tara);
            app.OffsetForca = offsetSalvo;

            % zerar pressão
            try
                chunkP = read(app.DaqSession, 10, "OutputFormat", "Matrix");
                app.OffsetPressao = mean(chunkP(:,2));
            catch
                app.OffsetPressao = 0;
            end

            barProgresso.Value = 0;

            lblTara.Text = ['2) Tara OK (Zero: ' num2str(offsetSalvo,'%.4f') ' V)'];
            lblTara.FontColor = CorVerde;

            lblCarga.Enable = 'on';
            lblCarga.FontColor = CorTexto;
            lblCarga.FontWeight = 'bold';

            lblStatus.Text = 'Coloque o peso e clique em Calibrar.';

            btnProximo.Text = 'Calibrar';
            btnProximo.Enable = 'on';
            passoAtual = 2;
            return;
        end

        % ------------------------------------------------------------
        % PASSO 2 – Leitura com Peso
        % ------------------------------------------------------------
        if passoAtual == 2

            btnProximo.Enable = 'off';
            lblStatus.Text = 'Lendo Carga...';

            dados_peso = [];

            for i = 1:10
                barProgresso.Value = i * 10;

                try
                    chunk = read(app.DaqSession, 100, "OutputFormat", "Matrix");
                catch
                    uialert(calibFig, ...
                        "Falha na leitura do DAQ durante a calibração.", ...
                        "Erro");
                    close(calibFig);
                    return;
                end

                dados_peso = [dados_peso; chunk(:,1)];
                drawnow;
            end

            leituraComPeso = mean(dados_peso);
            massa = edtMassa.Value;
            ForcaN = massa * 9.80665;
            DeltaV = leituraComPeso - offsetSalvo;

            if abs(DeltaV) < 0.002
                uialert(calibFig, 'Erro: Sem variação detectada.', 'Erro');
                btnProximo.Enable = 'on';
                barProgresso.Value = 0;
                return;
            end

            novoFator = ForcaN / DeltaV;

% Aplica o novo fator ao app
app.FatorForca = novoFator;

% ============================================================
% SALVA PERMANENTEMENTE NO ARQUIVO TXT
% ============================================================
try
    save("fator_forca.txt", "novoFator", "-ascii");
catch
    uialert(calibFig, ...
        "O fator foi calibrado, mas não pôde ser salvo no arquivo fator_forca.txt.", ...
        "Aviso");
end

barProgresso.Value = 100;
lblStatus.Text = 'Sucesso! Fator Atualizado.';
lblStatus.FontColor = CorVerde;

msgFinal = sprintf(['Calibração Concluída!\n\n' ...
                    'Massa: %.3f kg\n' ...
                    'Novo Fator (salvo): %.2f N/V'], ...
                    massa, novoFator);

uialert(calibFig, msgFinal, 'Concluído', 'Icon', 'success', ...
    'CloseFcn', @(o,e) close(calibFig)); 
        end

    end
        
     
        end

        % Button pushed function: Limpar
        function LimparButtonPushed(app, event)
           
            % 1. Zera memória visual
app.HistoryTime = [];
app.HistoryForca = [];
app.HistoryPressao = [];

% 2. Zera Relógio
app.TimeOffset = 0;
app.T0 = [];

% 3. Limpa Tela
cla(app.Grafico1);
cla(app.Grafico2);

% 4. Zera mostradores (se existirem)
if isprop(app, 'ForcaAtualField')
    app.ForcaAtualField.Value = 0;
    app.PressaoAtualField.Value = 0;
    app.ForcaMaximaField.Value = 0;
    app.PressaoMaximaField.Value = 0;
end

if isprop(app, 'CampoPicoForca')
    app.CampoPicoForca.Value = 0;
    app.CampoPicoPressao.Value = 0;
end

% 5. Zera buffers de gravação
app.RecTime = [];
app.RecForce = [];
app.RecPressure = [];

% 6. Opcional: Se quiser apagar linhas no gráfico
drawnow;
        
        end

        % Button pushed function: Tarar
        function TararButtonPushed(app, event)
% ============================================================
% 1. Verifica Hardware (sessão válida)
% ============================================================
if isempty(app.DaqSession) || ~isvalid(app.DaqSession)
    app.DaqSession = [];
    ConfigurarDAQ(app);

    % Se ainda assim não houver DAQ, aborta
    if isempty(app.DaqSession)
        uialert(app.UIFigure, ...
            "DAQ não encontrado. Conecte a interface NI e tente novamente.", ...
            "Erro de Hardware");
        return;
    end
end

% ============================================================
% 2. Verifica se o teste está rodando agora
% ============================================================
estavaRodando = app.IsRunning;

try
    % Se estiver rodando, pausa o DAQ
    if estavaRodando
        try
            stop(app.DaqSession);
        catch
            % Caso já tenha caído, apenas ignora
        end

        % Evita que o gráfico volte ao zero
        if ~isempty(app.HistoryTime)
            app.TimeOffset = app.HistoryTime(end);
        end
    end

    % ============================================================
    % 3. Lê o ZERO (média de 500 amostras)
    % ============================================================
    try
        data = read(app.DaqSession, 500, "OutputFormat", "Matrix");
    catch
        uialert(app.UIFigure, ...
            "Falha na leitura do DAQ durante a tara. O dispositivo pode ter sido desconectado.", ...
            "Erro");
        return;
    end

    % Corrige offset de força e pressão
    app.OffsetForca   = mean(data(:, 1));
    app.OffsetPressao = mean(data(:, 2));

    % ============================================================
    % 4. Retoma o teste automaticamente
    % ============================================================
    if estavaRodando
        try
            start(app.DaqSession, "Continuous");
        catch
            uialert(app.UIFigure, ...
                "DAQ não pôde ser reiniciado após tarar. Reconecte o hardware.", ...
                "Erro");
        end
    else
        uialert(app.UIFigure, ...
    "Sensores Zerados (Tara Definida).", ...
    "Sucesso", ...
    "Icon", "success");
    end

catch ME
    uialert(app.UIFigure, ...
        "Erro ao tentar Tarar: " + ME.message, ...
        "Erro");
end
        end

        % Button pushed function: Gravar
        function GravarButtonPushed(app, event)
           % ============================================================
% 1. Segurança: Só grava se o sistema já estiver rodando
% ============================================================
if ~app.IsRunning
    uialert(app.UIFigure, ...
        "Clique em INICIAR primeiro.", ...
        "Erro");
    return;
end

% ============================================================
% 2. Verifica se o DAQ está válido
% ============================================================
if isempty(app.DaqSession) || ~isvalid(app.DaqSession)
    uialert(app.UIFigure, ...
        "DAQ não está ativo. Reconecte o hardware e inicie a aquisição.", ...
        "Erro de Hardware");
    return;
end

% ============================================================
% 3. Limpa os buffers de gravação
% ============================================================
app.RecTime     = [];
app.RecForce    = [];
app.RecPressure = [];

% ============================================================
% 4. Tenta ativar o modo gravação
% ============================================================
try
    app.IsRecording = true;

    % Feedback visual
    app.Gravar.Text = "GRAVANDO...";
    app.Gravar.BackgroundColor = [1 0 0];   % Vermelho
    app.Gravar.FontColor = [1 1 1];         % Branco
    app.Gravar.Enable = 'off';              % Trava botão

catch ME
    % Se falhar, desfaz gravação e alerta
    app.IsRecording = false;

    app.Gravar.Text = "Iniciar Gravação";
    app.Gravar.BackgroundColor = [0.25 0.25 0.25];
    app.Gravar.FontColor = [1 1 1];
    app.Gravar.Enable = 'on';

    uialert(app.UIFigure, ...
        "Erro ao ativar gravação: " + ME.message, ...
        "Erro");
end

       
        end

        % Value changed function: NCheckBox
        function NCheckBoxValueChanged(app, event)
if app.NCheckBox.Value

    % Desmarca as outras unidades
    app.KgCheckBox.Value = false;
    if isprop(app, 'gCheckBox')
        app.gCheckBox.Value = false;
    end

    % Define unidade
    app.MultUnidade = 1.0;
    app.Grafico2.YLabel.String = "Força (N)";

else
    % Evita desmarcar tudo — mantém N ativo
    app.NCheckBox.Value = true;
end

        end

        % Value changed function: KgCheckBox
        function KgCheckBoxValueChanged(app, event)
 if app.KgCheckBox.Value

    % Desmarca as outras unidades
    app.NCheckBox.Value = false;
    if isprop(app, 'gCheckBox')
        app.gCheckBox.Value = false;
    end

    % Define unidade (kgf)
    app.MultUnidade = 0.10197;
    app.Grafico2.YLabel.String = "Força (kgf)";

else
    % Evita desmarcar tudo — mantém kgf ativo
    app.KgCheckBox.Value = true;
end
 
            
        end

        % Value changed function: gCheckBox
        function gCheckBoxValueChanged(app, event)
 if app.gCheckBox.Value

    % Desmarca as outras unidades
    app.NCheckBox.Value = false;
    app.KgCheckBox.Value = false;

    % Define unidade (gramas-força)
    app.MultUnidade = 101.97;
    app.Grafico2.YLabel.String = "Força (g)";

else
    % Evita desmarcar tudo — mantém g ativo
    app.gCheckBox.Value = true;
end
    
        end

        % Callback function
        function ResetarAplicativoMenuSelected(app, event)
% ============================================================
% 1. Parar Aquisição, se estiver rodando
% ============================================================
if app.IsRunning
    try
        stop(app.DaqSession);
    catch
        % Se o DAQ estiver desconectado, apenas ignore o erro
    end
end

% ============================================================
% 2. Resetar variáveis críticas de runtime
% ============================================================
app.IsRunning      = false;
app.IsRecording    = false;

app.HistoryTime    = [];
app.HistoryForca   = [];
app.HistoryPressao = [];

app.RecTime        = [];
app.RecForce       = [];
app.RecPressure    = [];

% ============================================================
% 3. Resetar referências de tempo e offsets
% ============================================================
app.TimeOffset    = 0;
app.T0            = [];
app.OffsetForca   = 0;
app.OffsetPressao = 0;

% ============================================================
% 4. Limpa os gráficos
% ============================================================
cla(app.Grafico1);
cla(app.Grafico2);

% ============================================================
% 5. Resetar mostradores visuais
% ============================================================
if isprop(app, 'ForcaAtualField')
    app.ForcaAtualField.Value      = 0;
    app.ForcaMaximaField.Value     = 0;
    app.PressaoAtualField.Value    = 0;
    app.PressaoMaximaField.Value   = 0;
end

if isprop(app, 'CampoPicoForca')
    app.CampoPicoForca.Value       = 0;
    app.CampoPicoPressao.Value     = 0;
end

app.StatusEditField.Value = "Desconectado";
app.StatusEditField.BackgroundColor = [0.149 0.149 0.149];  % fundo normal
app.StatusEditField.FontColor = [1 0 0];                   % texto vermelho
app.Lamp.Color = [1 0 0];                                  % lâmpada vermelha

app.IniciarAquisicao.Enable = 'on';
app.PararAquisicao.Enable   = 'off';

% ============================================================
% 6. Tenta recriar o hardware (Reset Real)
% ============================================================
try
    daqreset;
    app.DaqSession = [];

    ConfigurarDAQ(app);

    if isempty(app.DaqSession)
        error("DAQ não encontrado após o reset.");
    end

    % Lê um novo zero
    try
        dataInicial = read(app.DaqSession, 10, "OutputFormat", "Matrix");
        app.OffsetForca   = mean(dataInicial(:,1));
        app.OffsetPressao = mean(dataInicial(:,2));
    catch
        app.OffsetForca   = 0;
        app.OffsetPressao = 0;
    end

    % Inicia monitoramento contínuo
    app.IsRunning = true;
    start(app.DaqSession, "Continuous");

    % Atualiza status
    app.StatusEditField.Value = "Conectado";
    app.StatusEditField.BackgroundColor = [0.149 0.149 0.149];  % fundo normal
    app.StatusEditField.FontColor = [0 1 0];                    % texto verde
    app.Lamp.Color = [0 1 0];                                   % lamp verde

    app.IniciarAquisicao.Enable = 'off';
    app.PararAquisicao.Enable   = 'on';

    uialert(app.UIFigure, ...
        "Sistema Resetado e Monitoramento Reiniciado.", ...
        "Reset Concluído", ...
        "Icon", "info");

catch ME

    app.StatusEditField.Value = "Desconectado";
    app.StatusEditField.BackgroundColor = [1.0 0.6 0.6];
    app.Lamp.Color = [1 0 0];

    app.IniciarAquisicao.Enable = 'on';
    app.PararAquisicao.Enable   = 'off';

    uialert(app.UIFigure, ...
        "RESET FORÇADO! Hardware não encontrado.\nClique em INICIAR para tentar reconectar.\n\nDetalhes: " + ME.message, ...
        "Erro de Recarga", ...
        "Icon", "error");
end
        end

        % Button pushed function: AtualizarPortaButton
        function AtualizarPortaButtonButtonPushed(app, event)
try
    portaAnterior = app.DropDownDevID.Value;

    % Carrega lista de portas disponíveis
    portas = serialportlist("available");

    if isempty(portas)
        app.DropDownDevID.Items = {"Nenhuma porta encontrada"};
        app.DropDownDevID.Value = "Nenhuma porta encontrada";

        uialert(app.UIFigure, ...
            "Nenhuma porta serial foi encontrada no sistema.", ...
            "Aviso");
        return;
    end

    % Atualiza lista
    app.DropDownDevID.Items = portas;

    % Se a porta antiga ainda existe, mantém selecionada
    if any(strcmp(portaAnterior, portas))
        app.DropDownDevID.Value = portaAnterior;
    else
        % Seleciona automaticamente a primeira porta
        app.DropDownDevID.Value = portas{1};

        uialert(app.UIFigure, ...
            "A porta anteriormente selecionada não está mais disponível.\n" + ...
            "Foi selecionada automaticamente a primeira porta da lista.", ...
            "Porta Atualizada");
    end

catch ME
    uialert(app.UIFigure, ...
        "Erro ao atualizar lista de portas:\n" + ME.message, ...
        "Erro");
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafico1 = uiaxes(app.UIFigure);
            title(app.Grafico1, 'Pressão')
            xlabel(app.Grafico1, 'Tempo(s)')
            ylabel(app.Grafico1, 'Pressão (PSI)')
            zlabel(app.Grafico1, 'Z')
            app.Grafico2 = uiaxes(app.UIFigure);
            title(app.Grafico2, 'Curva de Empuxo ')
            xlabel(app.Grafico2, 'Tempo (s)')
            ylabel(app.Grafico2, 'Força')
            zlabel(app.Grafico2, 'Z')
            app.Grafico1_2 = uiaxes(app.UIFigure);
            title(app.Grafico1_2, 'Temperatura')
            xlabel(app.Grafico1_2, 'Tempo(s)')
            ylabel(app.Grafico1_2, 'Temperautra C')
            zlabel(app.Grafico1_2, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Gravar = uibutton(app.Panel, 'push');
            app.Gravar.ButtonPushedFcn = createCallbackFcn(app, @GravarButtonPushed, true);
            app.Gravar.Text = 'Iniciar Aquisição';
            app.UnidadedeForaPanel = uipanel(app.Panel);
            app.UnidadedeForaPanel.Title = 'Unidade de Força';
            app.NCheckBox = uicheckbox(app.UnidadedeForaPanel);
            app.NCheckBox.ValueChangedFcn = createCallbackFcn(app, @NCheckBoxValueChanged, true);
            app.NCheckBox.Text = 'N';
            app.KgCheckBox = uicheckbox(app.UnidadedeForaPanel);
            app.KgCheckBox.ValueChangedFcn = createCallbackFcn(app, @KgCheckBoxValueChanged, true);
            app.KgCheckBox.Text = 'Kg';
            app.gCheckBox = uicheckbox(app.UnidadedeForaPanel);
            app.gCheckBox.ValueChangedFcn = createCallbackFcn(app, @gCheckBoxValueChanged, true);
            app.gCheckBox.Text = 'g';
            app.Calibrar = uibutton(app.Panel, 'push');
            app.Calibrar.ButtonPushedFcn = createCallbackFcn(app, @CalibrarButtonPushed, true);
            app.Calibrar.Text = 'Calibrar ';
            app.IniciarAquisicao = uibutton(app.Panel, 'push');
            app.IniciarAquisicao.ButtonPushedFcn = createCallbackFcn(app, @IniciarAquisicaoButtonPushed, true);
            app.IniciarAquisicao.Text = 'Iniciar';
            app.Lamp = uilamp(app.Panel);
            app.PararAquisicao = uibutton(app.Panel, 'push');
            app.PararAquisicao.ButtonPushedFcn = createCallbackFcn(app, @PararAquisicaoButtonPushed, true);
            app.PararAquisicao.Text = 'Parar';
            app.Tarar = uibutton(app.Panel, 'push');
            app.Tarar.ButtonPushedFcn = createCallbackFcn(app, @TararButtonPushed, true);
            app.Tarar.Text = 'Tarar';
            app.Limpar = uibutton(app.UIFigure, 'push');
            app.Limpar.ButtonPushedFcn = createCallbackFcn(app, @LimparButtonPushed, true);
            app.Limpar.Text = 'Limpar ';
            app.SelecionarPortaUSBLabel = uilabel(app.UIFigure);
            app.SelecionarPortaUSBLabel.Text = {'Selecionar Porta: '; ''};
            app.DropDownDevID = uidropdown(app.UIFigure);
            app.AtualizarPortaButton = uibutton(app.UIFigure, 'push');
            app.AtualizarPortaButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarPortaButtonButtonPushed, true);
            app.AtualizarPortaButton.Text = '';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.ArquivoPanel = uipanel(app.UIFigure);
            app.ArquivoPanel.Title = 'Arquivo';
            app.SISTEMADEINSTRUMENTAOLabel = uilabel(app.ArquivoPanel);
            app.SISTEMADEINSTRUMENTAOLabel.Text = 'SISTEMA DE INSTRUMENTAÇÂO';
            app.AQUISIODEDADOSBANCADADETESTEESTTICOLabel = uilabel(app.ArquivoPanel);
            app.AQUISIODEDADOSBANCADADETESTEESTTICOLabel.Text = 'AQUISIÇÂO DE DADOS - BANCADA DE TESTE ESTÁTICO';
            app.MonitoramentoPanel = uipanel(app.UIFigure);
            app.MonitoramentoPanel.Title = 'Monitoramento';
            app.EmpuxoAtualLabel = uilabel(app.MonitoramentoPanel);
            app.EmpuxoAtualLabel.Text = 'Empuxo Atual ';
            app.ForcaAtualField = uieditfield(app.MonitoramentoPanel, 'numeric');
            app.EmpuxoMximoLabel = uilabel(app.MonitoramentoPanel);
            app.EmpuxoMximoLabel.Text = 'Empuxo Máximo';
            app.ForcaMaximaField = uieditfield(app.MonitoramentoPanel, 'numeric');
            app.PressoAtualEditFieldLabel = uilabel(app.MonitoramentoPanel);
            app.PressoAtualEditFieldLabel.Text = 'Pressão Atual ';
            app.PressaoAtualField = uieditfield(app.MonitoramentoPanel, 'numeric');
            app.PressoMximaEditFieldLabel = uilabel(app.MonitoramentoPanel);
            app.PressoMximaEditFieldLabel.Text = 'Pressão  Máxima';
            app.PressaoMaximaField = uieditfield(app.MonitoramentoPanel, 'numeric');
            app.ImpulsototalEditFieldLabel = uilabel(app.MonitoramentoPanel);
            app.ImpulsototalEditFieldLabel.Text = 'Impulso total';
            app.ImpulsoTotalField = uieditfield(app.MonitoramentoPanel, 'numeric');
            app.EmpuxomdioLabel = uilabel(app.MonitoramentoPanel);
            app.EmpuxomdioLabel.Text = 'Empuxo médio';
            app.EmpuxoMedioField = uieditfield(app.MonitoramentoPanel, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.CONEXOLabel = uilabel(app.UIFigure);
            app.CONEXOLabel.Text = 'CONEXãO';
            app.PORTASERIALDropDown = uidropdown(app.UIFigure);
            app.PORTASERIALDropDown.Items = {'COM 3', 'Option 2', 'Option 3', 'Option 4'};
            app.PORTASERIALDropDown.Value = 'COM 3';
            app.CONECTADOButton = uibutton(app.UIFigure, 'push');
            app.CONECTADOButton.Text = 'CONECTADO';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.CONECTARButton = uibutton(app.UIFigure, 'push');
            app.CONECTARButton.Text = 'CONECTAR';
            app.CONTROLELabel = uilabel(app.UIFigure);
            app.CONTROLELabel.Text = 'CONTROLE';
            app.INICIARButton = uibutton(app.UIFigure, 'push');
            app.INICIARButton.Text = 'INICIAR';
            app.INICIARButton_2 = uibutton(app.UIFigure, 'push');
            app.INICIARButton_2.Text = 'INICIAR';
            app.CalibrarButton = uibutton(app.UIFigure, 'push');
            app.CalibrarButton.Text = 'Calibrar ';
            app.TararButton = uibutton(app.UIFigure, 'push');
            app.TararButton.Text = 'Tarar ';
            app.LimparButton = uibutton(app.UIFigure, 'push');
            app.LimparButton.Text = 'Limpar';
            app.UNIDADEDEFORALabel = uilabel(app.UIFigure);
            app.UNIDADEDEFORALabel.Text = 'UNIDADE DE FORÇA';
            app.NCheckBox_2 = uicheckbox(app.UIFigure);
            app.NCheckBox_2.Text = 'N';
            app.kgfCheckBox = uicheckbox(app.UIFigure);
            app.kgfCheckBox.Text = 'kgf';
            app.gfCheckBox = uicheckbox(app.UIFigure);
            app.gfCheckBox.Text = 'gf';
            app.MONITORAMENTOLabel = uilabel(app.UIFigure);
            app.MONITORAMENTOLabel.Text = 'MONITORAMENTO';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EmpuxoAtualLabel_3 = uilabel(app.UIFigure);
            app.EmpuxoAtualLabel_3.Text = 'Empuxo Atual';
            app.NLabel = uilabel(app.UIFigure);
            app.NLabel.Text = 'N';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EmpuxoMximoLabel_2 = uilabel(app.UIFigure);
            app.EmpuxoMximoLabel_2.Text = 'Empuxo Máximo';
            app.NLabel_2 = uilabel(app.UIFigure);
            app.NLabel_2.Text = 'N';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.Text = '';
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.TemperaturaatualLabel = uilabel(app.UIFigure);
            app.TemperaturaatualLabel.Text = 'Temperatura atual';
            app.NLabel_7 = uilabel(app.UIFigure);
            app.NLabel_7.Text = 'N';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.Text = '';
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.ImpulsototalLabel = uilabel(app.UIFigure);
            app.ImpulsototalLabel.Text = 'Impulso total';
            app.NsLabel = uilabel(app.UIFigure);
            app.NsLabel.Text = 'N - s';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.Text = '';
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.PressoAtualLabel = uilabel(app.UIFigure);
            app.PressoAtualLabel.Text = 'Pressão Atual ';
            app.PSILabel_2 = uilabel(app.UIFigure);
            app.PSILabel_2.Text = 'PSI';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.Text = '';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.PressoMximaLabel = uilabel(app.UIFigure);
            app.PressoMximaLabel.Text = 'Pressão Máxima';
            app.PSILabel = uilabel(app.UIFigure);
            app.PSILabel.Text = 'PSI';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.Text = '';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.TemperaturaMximaLabel = uilabel(app.UIFigure);
            app.TemperaturaMximaLabel.Text = 'Temperatura Máxima';
            app.NLabel_11 = uilabel(app.UIFigure);
            app.NLabel_11.Text = 'N';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.Text = '';
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-----';
            app.NLabel_12 = uilabel(app.UIFigure);
            app.NLabel_12.Text = 'N';
            app.EmpuxoAtualLabel_2 = uilabel(app.UIFigure);
            app.EmpuxoAtualLabel_2.Text = 'Empuxo Atual ';
            app.ForcaAtualField_2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppBancadaPrincipal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end